classdef activity_browser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        FileMenu    matlab.ui.container.Menu
        LoadMenu    matlab.ui.container.Menu
        SaveAsMenu  matlab.ui.container.Menu
        EditMenu    matlab.ui.container.Menu
        ViewMenu    matlab.ui.container.Menu
        MainGrid    matlab.ui.container.GridLayout
        TL_Button   matlab.ui.control.Button
        tLabel      matlab.ui.control.Label
        TimePanel   matlab.ui.container.Panel
        TimeGrid    matlab.ui.container.GridLayout
        NewRow      matlab.ui.control.Button
        NewCol      matlab.ui.control.Button
    end

    
    properties (Access = private)
        parent_app; % Description
    end
    
    methods (Access = private)
        
        function AddNeuron(app,event)
            app.AddAxes(app.MainGrid, event.Value, event.Source.Layout.Row, event.Source.Layout.Column);
            delete(event.Source);
        end

        function GrowGrid(app,event)
            num_col = size(app.MainGrid.ColumnWidth,2);
            num_row = size(app.MainGrid.RowHeight,2);

            if strcmp(event.Source.Tag,'col')
                app.MainGrid.ColumnWidth(num_col) = {'1x'};
                app.MainGrid.ColumnWidth(num_col+1) = {25};
            elseif strcmp(event.Source.Tag,'row')
                app.MainGrid.RowHeight(num_row) = {'1x'};
                app.MainGrid.RowHeight(num_row+1) = {25};
            end

            app.AddDrop(app.MainGrid,event.Source.Layout.Row,event.Source.Layout.Column)

            if strcmp(event.Source.Tag,'col')
                event.Source.Layout.Row = event.Source.Layout.Row;
                event.Source.Layout.Column = event.Source.Layout.Column+1;
            elseif strcmp(event.Source.Tag,'row')
                event.Source.Layout.Row = event.Source.Layout.Row+1;
                event.Source.Layout.Column = event.Source.Layout.Column;
            end

            app.FillGrid(num_col,num_row)

        end

        function FillGrid(app,num_col,num_row)
            cell_count = num_col+num_row;
            i = cell_count-size(app.MainGrid.Children,1)
            
            for i=1:num_col
                for n=1:num_row
                    if ~exist(app.MainGrid.Children(i))
                        app.AddDrop(app.MainGrid,i);
                    end
                end
            end
        end

        function AddDrop(app, parent, row, column)
            DropDown = uidropdown(parent);
            DropDown.Items = {'Sample Neuron #1','Sample Neuron #2','Sample Neuron #3'};
            DropDown.Placeholder = 'Select Neuron';
            DropDown.ValueChangedFcn = createCallbackFcn(app, @AddNeuron, true);
            DropDown.Layout.Row = row;
            DropDown.Layout.Column = column;
        end

        function AddAxes(app, parent, name, row, column)
            new_axes = uiaxes(parent);
            title(new_axes, name)
            xlabel(new_axes, 't')
            ylabel(new_axes, 'Ca+')
            zlabel(new_axes, 'Z')
            new_axes.Layout.Row = row;
            new_axes.Layout.Column = column;
        end

        function CreateNeuron(app, caller, event)
            % Create TL_Panel
            new_panel = uipanel(app.MainGrid);

            if grid_space(1) == max(size(app.MainGrid.ColumnWidth))
                new_panel.Layout.Column = grid_space(2)+1;
            else
                new_panel.Layout.Column = grid_space;
            end

            if grid_space(2) == max(size(app.MainGrid.RowHeight))
                new_panel.Layout.Row = grid_space(1)+1;
            end

            % Create TL_Grid
            new_grid = uigridlayout(app.TL_Panel);
            new_grid.ColumnWidth = {'1x'};
            new_grid.RowHeight = {'1x'};

            % Create TL_Button
            new_button = uibutton(app.TL_Grid, 'push');
            new_button.ButtonPushedFcn = createCallbackFcn(app, @AddNeuron, true);
            new_button.FontWeight = 'bold';
            new_button.Layout.Row = 1;
            new_button.Layout.Column = 1;
            new_button.Text = 'Add Neuron';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parent_app)

            app.parent_app = parent_app;
            
            % Create the 1D axes
            app.time_ax = axes(app.TimeGrid,'Color','red','YColor','none','XLim',[0,30],'YTick',[], 'XTick',[],'TickDir','in','TickLength',[0.02,0.035],'XMinorTick', 'on','Units','pixels','Position',app.TimePanel.Position);
            %set(app.time_ax,'XColor',[1 1 1])
            
            % Disable the interactivity & toolbar visibility
            disableDefaultInteractivity(app.time_ax);
            app.time_ax.Toolbar.Visible = 'off';
            
            % Add the line ROI
            app.time_line = images.roi.Line(app.time_ax,'Position',[0.1,app.TimePanel.Position(4)/40;5,app.TimePanel.Position(4)/40],'LineWidth',50,'Color',[0,1,0]);
            
            % Redraw custom tick lines over the ROI
            for i=0:1:30
                line(app.time_ax,[i,i],[0,0.02],'Color',[1 1 1],'LineWidth',1.5);
            end
        
            % Redraw the tick labels over the ROI
            app.parent_app.ticklabelinside(app.time_ax,'x',0);
            
            % Add a listener that will trigger a callback function titled "lMoving" when user
            % moves the ROI endpoints or the line ROI as a whole
            addlistener(app.time_line,'MovingROI',@(varargin)app.parent_app.lMoving(app,app.time_line));

        end

        % Button pushed function: TL_Button
        function TL_ButtonPushed(app, event)
            app.AddDrop(app.MainGrid,event.Source.Layout.Row,event.Source.Layout.Column);
            delete(event.Source);
        end

        % Button pushed function: NewCol
        function NewColPushed(app, event)
            app.GrowGrid(event)
        end

        % Button pushed function: NewRow
        function NewRowPushed(app, event)
            app.GrowGrid(event)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadMenu = uimenu(app.FileMenu);
            app.LoadMenu.Text = 'Load';
            app.SaveAsMenu = uimenu(app.FileMenu);
            app.SaveAsMenu.Text = 'Save As...';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Text = 'Edit';
            app.ViewMenu = uimenu(app.UIFigure);
            app.ViewMenu.Text = 'View';
            app.MainGrid = uigridlayout(app.UIFigure);
            app.NewCol = uibutton(app.MainGrid, 'push');
            app.NewCol.ButtonPushedFcn = createCallbackFcn(app, @NewColPushed, true);
            app.NewCol.Tag = 'col';
            app.NewCol.Text = '+';
            app.NewRow = uibutton(app.MainGrid, 'push');
            app.NewRow.ButtonPushedFcn = createCallbackFcn(app, @NewRowPushed, true);
            app.NewRow.Tag = 'row';
            app.NewRow.Text = '+';
            app.TimePanel = uipanel(app.MainGrid);
            app.TimeGrid = uigridlayout(app.TimePanel);
            app.tLabel = uilabel(app.MainGrid);
            app.tLabel.Text = 't';
            app.TL_Button = uibutton(app.MainGrid, 'push');
            app.TL_Button.ButtonPushedFcn = createCallbackFcn(app, @TL_ButtonPushed, true);
            app.TL_Button.Text = 'Add Neuron';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = activity_browser(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end