classdef channel_selector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        zSlicesListBox                 matlab.ui.control.ListBox
        ZSlicesLabel                   matlab.ui.control.Label
        RGBValueChartPanel             matlab.ui.container.Panel
        GridLayout                     matlab.ui.container.GridLayout
        gut_z                          matlab.ui.control.NumericEditField
        gut_y                          matlab.ui.control.NumericEditField
        gut_x                          matlab.ui.control.NumericEditField
        lyso_z                         matlab.ui.control.NumericEditField
        lyso_y                         matlab.ui.control.NumericEditField
        lyso_x                         matlab.ui.control.NumericEditField
        gfp_z                          matlab.ui.control.NumericEditField
        gfp_y                          matlab.ui.control.NumericEditField
        gfp_x                          matlab.ui.control.NumericEditField
        white_z                        matlab.ui.control.NumericEditField
        white_y                        matlab.ui.control.NumericEditField
        white_x                        matlab.ui.control.NumericEditField
        blue_z                         matlab.ui.control.NumericEditField
        blue_y                         matlab.ui.control.NumericEditField
        blue_x                         matlab.ui.control.NumericEditField
        green_z                        matlab.ui.control.NumericEditField
        green_y                        matlab.ui.control.NumericEditField
        green_x                        matlab.ui.control.NumericEditField
        red_z                          matlab.ui.control.NumericEditField
        red_y                          matlab.ui.control.NumericEditField
        red_x                          matlab.ui.control.NumericEditField
        ZLabel                         matlab.ui.control.Label
        YLabel                         matlab.ui.control.Label
        XLabel                         matlab.ui.control.Label
        Label_8                        matlab.ui.control.Label
        Label_7                        matlab.ui.control.Label
        Label_6                        matlab.ui.control.Label
        Label_5                        matlab.ui.control.Label
        Label_4                        matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        gut_b                          matlab.ui.control.NumericEditField
        gut_g                          matlab.ui.control.NumericEditField
        gut_r                          matlab.ui.control.NumericEditField
        lyso_b                         matlab.ui.control.NumericEditField
        lyso_g                         matlab.ui.control.NumericEditField
        lyso_r                         matlab.ui.control.NumericEditField
        gfp_b                          matlab.ui.control.NumericEditField
        gfp_g                          matlab.ui.control.NumericEditField
        gfp_r                          matlab.ui.control.NumericEditField
        white_b                        matlab.ui.control.NumericEditField
        white_g                        matlab.ui.control.NumericEditField
        white_r                        matlab.ui.control.NumericEditField
        blue_b                         matlab.ui.control.NumericEditField
        blue_g                         matlab.ui.control.NumericEditField
        blue_r                         matlab.ui.control.NumericEditField
        green_r                        matlab.ui.control.NumericEditField
        green_g                        matlab.ui.control.NumericEditField
        green_b                        matlab.ui.control.NumericEditField
        red_b                          matlab.ui.control.NumericEditField
        red_g                          matlab.ui.control.NumericEditField
        red_r                          matlab.ui.control.NumericEditField
        gut_measure                    matlab.ui.control.Button
        lyso_measure                   matlab.ui.control.Button
        gfp_measure                    matlab.ui.control.Button
        white_measure                  matlab.ui.control.Button
        blue_measure                   matlab.ui.control.Button
        green_measure                  matlab.ui.control.Button
        red_measure                    matlab.ui.control.Button
        BLabel                         matlab.ui.control.Label
        GLabel                         matlab.ui.control.Label
        RLabel                         matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        GutCheckbox                    matlab.ui.control.CheckBox
        LysoCheckbox                   matlab.ui.control.CheckBox
        GFPCheckbox                    matlab.ui.control.CheckBox
        WCheckbox                      matlab.ui.control.CheckBox
        BCheckbox                      matlab.ui.control.CheckBox
        GCheckbox                      matlab.ui.control.CheckBox
        RCheckbox                      matlab.ui.control.CheckBox
        ChannelLabel                   matlab.ui.control.Label
        GutFluorescenceLabel           matlab.ui.control.Label
        LysosomeAutofluroescenceLabel  matlab.ui.control.Label
        GFPLabel                       matlab.ui.control.Label
        WhiteLabel                     matlab.ui.control.Label
        BlueLabel                      matlab.ui.control.Label
        GreenLabel                     matlab.ui.control.Label
        RedLabel                       matlab.ui.control.Label
        Slider                         matlab.ui.control.Slider
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        parent_app; % Description
        image_data;
        image_view;
        image_gamma;
        red; % Color indices.
        green;
        blue;
        white;
        gfp;
        dic; 
    end
    
    methods (Access = private)
        
        %% Draw the annotated image (image volume & neuron markers).
        function DrawImageData(app, slice)

            % Is there an image?
            if isempty(app.image_data)
                return;
            end

            % Determine the color channel indices.
            red = app.red;
            green = app.green;
            blue = app.blue;
            white = app.white;
            dic = app.dic;
            gfp = app.gfp;

            % Determine the channel=color assignments for displaying.
            color_indices = [red, green, blue];

            % Draw the 3 color channels.
            app.image_view = app.image_data(:,:,slice,color_indices);

            % Remove unchecked color channels.
            if ~app.RCheckbox.Value % Red
                app.image_view(:,:,slice,1) = 0;
            end
            if ~app.GCheckbox.Value % Green
                app.image_view(:,:,slice,2) = 0;
            end
            if ~app.BCheckbox.Value % Blue
                app.image_view(:,:,slice,3) = 0;
            end

            % Add in the white channel.
            if app.WCheckbox.Value % White

                % Compute the white channel.
                wchannel = app.image_data(:,:,slice,white);

                % Adjust the gamma.
                W_i = app.gamma_RGBW_DIC_GFP_index(4); % white channel gamma index
                if length(app.image_gamma) >= W_i && app.image_gamma(W_i) ~= 1
                    wchannel = imadjustn(wchannel,[],[], app.image_gamma(W_i));
                end

                % Add the white channel.
                app.image_view = app.image_view + repmat(wchannel, [1,1,1,3]);
            end

            % Add in the GFP channel.
            if app.GFPCheckbox.Value % GFP

                % Compute the GFP channel.
                gfp_color = Program.GUIPreferences.instance().GFP_color;
                gfp_channel = app.image_data(:,:,slice,gfp);

                % Adjust the gamma.
                GFP_i = app.gamma_RGBW_DIC_GFP_index(6); % GFP channel gamma index
                if length(app.image_gamma) >= GFP_i && app.image_gamma(GFP_i) ~= 1
                    gfp_channel = imadjustn(gfp_channel,[],[], app.image_gamma(GFP_i));
                end

                % Add the GFP channel.
                gfp_channel = repmat(gfp_channel, [1,1,1,3]);
                gfp_channel(:,:,slice,~gfp_color) = 0;
                app.image_view = app.image_view + gfp_channel;
            end

            % Adjust the gamma.
            % Note: the image only shows RGB. We added the other channels
            % (W, DIC, GFP) to the RGB in order to show these as well.
            app.image_view = uint16(double(intmax('uint16')) * ...
                double(app.image_view)/double(max(app.image_view(:))));
            for c = 1:size(app.image_view, 4)
                if app.image_gamma(c) ~= 1
                    app.image_view(:,:,:,c) = ...
                        imadjustn(squeeze(app.image_view(:,:,:,c)),[],[], ...
                        app.image_gamma(c));
                end
            end
            app.image_view = double(app.image_view)/double(max(app.image_view(:)));

            % Redraw the max projection.
            % Note: the image only shows RGB. We added the other channels
            % (W, DIC, GFP) to the RGB in order to show these as well.
            image(app.UIAxes, squeeze(max(app.image_view,[],3)));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parent_app, image_data, image_gamma, red, green, blue, white, gfp, dic)
            app.parent_app = parent_app;
            app.image_data = image_data;
            app.image_gamma = image_gamma;

            app.red = str2double(red);
            app.green = str2double(green);
            app.blue = str2double(blue);
            app.white = str2double(white);
            app.gfp = str2double(gfp);
            app.dic = str2double(dic);

            C = {};

            for n = 1:size(app.image_data, 3)
                C{n} = ['Slice ' num2str(n)];
            end

            app.zSlicesListBox.Items = flip(C);
            app.zSlicesListBox.ItemsData = 1:size(app.image_data,3);
            app.zSlicesListBox.ItemsData = flip(app.zSlicesListBox.ItemsData);
            app.zSlicesListBox.Value = round(size(app.image_data, 3)/2);

            app.Slider.Limits = [0,size(app.image_data, 3)];
            app.Slider.Value = app.zSlicesListBox.Value;

            app.DrawImageData(app.Slider.Value);
        end

        % Callback function: not associated with a component
        function SaveButtonPushed(app, event)
            app.parent_app.debleed(app.RedCheckBox.Value,app.GreenCheckBox.Value,app.BlueCheckBox.Value,app.BackgroundCheckBox.Value,app.WhiteCheckBox.Value,app.GFPCheckBox.Value,app.LysosomeautofluorescenceCheckBox.Value,app.GutautofluorescenceCheckBox.Value);
            delete(app.UIFigure)
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = round(app.Slider.Value);

            if value == 0
                value = 1;
            elseif value > size(app.image_data,3)
                value = size(app.image_data,3);
            end

            app.zSlicesListBox.Value = value;
            app.DrawImageData(value);
        end

        % Value changed function: zSlicesListBox
        function zSlicesListBoxValueChanged(app, event)
            value = app.zSlicesListBox.Value;
            app.Slider.Value = value;
            app.DrawImageData(value);
        end

        % Button pushed function: red_measure
        function red_measureButtonPushed(app, event)
            set(app.Slider,'Enable','Off')
            check = uiconfirm(app.UIFigure,'Click on the pixel on this slice that best represents red.','Confirmation','Options',{'OK', 'Select different slice'},'DefaultOption','OK');

            switch check
                case 'OK'

                    color_roi = drawpoint(app.UIAxes);
                    pos = round(color_roi.Position);
                    delete(color_roi);

                    app.red_x.Value = round((pos(1)/app.UIAxes.XLim(2))*size(app.image_data,1));
                    app.red_y.Value = round((pos(2)/app.UIAxes.YLim(2))*size(app.image_data,1));
                    app.red_z.Value = round(app.Slider.Value);

                    app.red_r.Value = app.image_view(app.red_x.Value, app.red_y.Value, :, app.red);
                    app.red_g.Value = app.image_view(app.red_x.Value, app.red_y.Value, :, app.green);
                    app.red_b.Value = app.image_view(app.red_x.Value, app.red_y.Value, :, app.blue);

                case 'Select different slice'
                    return
            end

            set(app.Slider,'Enable','On')

        end

        % Button pushed function: green_measure
        function green_measureButtonPushed(app, event)
            set(app.Slider,'Enable','Off')
            check = uiconfirm(app.UIFigure,'Click on the pixel on this slice that best represents green.','Confirmation','Options',{'OK', 'Select different slice'},'DefaultOption','OK');

            switch check
                case 'OK'

                    color_roi = drawpoint(app.UIAxes);
                    pos = round(color_roi.Position);
                    delete(color_roi);

                    app.green_x.Value = round((pos(1)/app.UIAxes.XLim(2))*size(app.image_data,1));
                    app.green_y.Value = round((pos(2)/app.UIAxes.YLim(2))*size(app.image_data,1));
                    app.green_z.Value = round(app.Slider.Value);

                    app.green_r.Value = app.image_view(app.green_x.Value, app.green_y.Value, :, app.red);
                    app.green_g.Value = app.image_view(app.green_x.Value, app.green_y.Value, :, app.green);
                    app.green_b.Value = app.image_view(app.green_x.Value, app.green_y.Value, :, app.blue);

                case 'Select different slice'
                    return
            end

            set(app.Slider,'Enable','On')

        end

        % Button pushed function: blue_measure
        function blue_measureButtonPushed(app, event)
            set(app.Slider,'Enable','Off')
            check = uiconfirm(app.UIFigure,'Click on the pixel on this slice that best represents blue.','Confirmation','Options',{'OK', 'Select different slice'},'DefaultOption','OK');

            switch check
                case 'OK'

                    color_roi = drawpoint(app.UIAxes);
                    pos = round(color_roi.Position);
                    delete(color_roi);

                    app.blue_x.Value = round((pos(1)/app.UIAxes.XLim(2))*size(app.image_data,1));
                    app.blue_y.Value = round((pos(2)/app.UIAxes.YLim(2))*size(app.image_data,1));
                    app.blue_z.Value = round(app.Slider.Value);

                    app.blue_r.Value = app.image_view(app.blue_x.Value, app.blue_y.Value, :, app.red);
                    app.blue_g.Value = app.image_view(app.blue_x.Value, app.blue_y.Value, :, app.green);
                    app.blue_b.Value = app.image_view(app.blue_x.Value, app.blue_y.Value, :, app.blue);

                case 'Select different slice'
                    return
            end

            set(app.Slider,'Enable','On')

        end

        % Button pushed function: white_measure
        function white_measureButtonPushed(app, event)
            set(app.Slider,'Enable','Off')
            check = uiconfirm(app.UIFigure,'Click on the pixel on this slice that best represents white.','Confirmation','Options',{'OK', 'Select different slice'},'DefaultOption','OK');

            switch check
                case 'OK'

                    color_roi = drawpoint(app.UIAxes);
                    pos = round(color_roi.Position);
                    delete(color_roi);

                    app.white_x.Value = round((pos(1)/app.UIAxes.XLim(2))*size(app.image_data,1));
                    app.white_y.Value = round((pos(2)/app.UIAxes.YLim(2))*size(app.image_data,1));
                    app.white_z.Value = round(app.Slider.Value);

                    app.white_r.Value = app.image_view(app.white_x.Value, app.white_y.Value, :, app.red);
                    app.white_g.Value = app.image_view(app.white_x.Value, app.white_y.Value, :, app.green);
                    app.white_b.Value = app.image_view(app.white_x.Value, app.white_y.Value, :, app.blue);

                case 'Select different slice'
                    return
            end

            set(app.Slider,'Enable','On')

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Image')
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTickLabel = '';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.RGBValueChartPanel = uipanel(app.UIFigure);
            app.RGBValueChartPanel.Title = 'RGB Value Chart';
            app.GridLayout = uigridlayout(app.RGBValueChartPanel);
            app.RedLabel = uilabel(app.GridLayout);
            app.RedLabel.Text = 'Red';
            app.GreenLabel = uilabel(app.GridLayout);
            app.GreenLabel.Text = 'Green';
            app.BlueLabel = uilabel(app.GridLayout);
            app.BlueLabel.Text = 'Blue';
            app.WhiteLabel = uilabel(app.GridLayout);
            app.WhiteLabel.Text = 'White';
            app.GFPLabel = uilabel(app.GridLayout);
            app.GFPLabel.Text = 'GFP';
            app.LysosomeAutofluroescenceLabel = uilabel(app.GridLayout);
            app.LysosomeAutofluroescenceLabel.Text = 'Lysosome Autofluroescence';
            app.GutFluorescenceLabel = uilabel(app.GridLayout);
            app.GutFluorescenceLabel.Text = 'Gut Fluorescence';
            app.ChannelLabel = uilabel(app.GridLayout);
            app.ChannelLabel.Text = 'Channel';
            app.RCheckbox = uicheckbox(app.GridLayout);
            app.RCheckbox.Text = '';
            app.RCheckbox.Value = true;
            app.GCheckbox = uicheckbox(app.GridLayout);
            app.GCheckbox.Text = '';
            app.GCheckbox.Value = true;
            app.BCheckbox = uicheckbox(app.GridLayout);
            app.BCheckbox.Text = '';
            app.BCheckbox.Value = true;
            app.WCheckbox = uicheckbox(app.GridLayout);
            app.WCheckbox.Text = '';
            app.GFPCheckbox = uicheckbox(app.GridLayout);
            app.GFPCheckbox.Enable = 'off';
            app.GFPCheckbox.Text = '';
            app.LysoCheckbox = uicheckbox(app.GridLayout);
            app.LysoCheckbox.Enable = 'off';
            app.LysoCheckbox.Text = '';
            app.GutCheckbox = uicheckbox(app.GridLayout);
            app.GutCheckbox.Enable = 'off';
            app.GutCheckbox.Text = '';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = ' 👁';
            app.RLabel = uilabel(app.GridLayout);
            app.RLabel.Text = 'R';
            app.GLabel = uilabel(app.GridLayout);
            app.GLabel.Text = 'G';
            app.BLabel = uilabel(app.GridLayout);
            app.BLabel.Text = 'B';
            app.red_measure = uibutton(app.GridLayout, 'push');
            app.red_measure.ButtonPushedFcn = createCallbackFcn(app, @red_measureButtonPushed, true);
            app.red_measure.Text = '➤';
            app.green_measure = uibutton(app.GridLayout, 'push');
            app.green_measure.ButtonPushedFcn = createCallbackFcn(app, @green_measureButtonPushed, true);
            app.green_measure.Text = '➤';
            app.blue_measure = uibutton(app.GridLayout, 'push');
            app.blue_measure.ButtonPushedFcn = createCallbackFcn(app, @blue_measureButtonPushed, true);
            app.blue_measure.Text = '➤';
            app.white_measure = uibutton(app.GridLayout, 'push');
            app.white_measure.ButtonPushedFcn = createCallbackFcn(app, @white_measureButtonPushed, true);
            app.white_measure.Text = '➤';
            app.gfp_measure = uibutton(app.GridLayout, 'push');
            app.gfp_measure.Enable = 'off';
            app.gfp_measure.Text = '➤';
            app.lyso_measure = uibutton(app.GridLayout, 'push');
            app.lyso_measure.Enable = 'off';
            app.lyso_measure.Text = '➤';
            app.gut_measure = uibutton(app.GridLayout, 'push');
            app.gut_measure.Enable = 'off';
            app.gut_measure.Text = '➤';
            app.red_r = uieditfield(app.GridLayout, 'numeric');
            app.red_r.Editable = 'off';
            app.red_g = uieditfield(app.GridLayout, 'numeric');
            app.red_g.Editable = 'off';
            app.red_b = uieditfield(app.GridLayout, 'numeric');
            app.red_b.Editable = 'off';
            app.green_b = uieditfield(app.GridLayout, 'numeric');
            app.green_b.Editable = 'off';
            app.green_g = uieditfield(app.GridLayout, 'numeric');
            app.green_g.Editable = 'off';
            app.green_r = uieditfield(app.GridLayout, 'numeric');
            app.green_r.Editable = 'off';
            app.blue_r = uieditfield(app.GridLayout, 'numeric');
            app.blue_r.Editable = 'off';
            app.blue_g = uieditfield(app.GridLayout, 'numeric');
            app.blue_g.Editable = 'off';
            app.blue_b = uieditfield(app.GridLayout, 'numeric');
            app.blue_b.Editable = 'off';
            app.white_r = uieditfield(app.GridLayout, 'numeric');
            app.white_r.Editable = 'off';
            app.white_g = uieditfield(app.GridLayout, 'numeric');
            app.white_g.Editable = 'off';
            app.white_b = uieditfield(app.GridLayout, 'numeric');
            app.white_b.Editable = 'off';
            app.gfp_r = uieditfield(app.GridLayout, 'numeric');
            app.gfp_r.Editable = 'off';
            app.gfp_g = uieditfield(app.GridLayout, 'numeric');
            app.gfp_g.Editable = 'off';
            app.gfp_b = uieditfield(app.GridLayout, 'numeric');
            app.gfp_b.Editable = 'off';
            app.lyso_r = uieditfield(app.GridLayout, 'numeric');
            app.lyso_r.Editable = 'off';
            app.lyso_g = uieditfield(app.GridLayout, 'numeric');
            app.lyso_g.Editable = 'off';
            app.lyso_b = uieditfield(app.GridLayout, 'numeric');
            app.lyso_b.Editable = 'off';
            app.gut_r = uieditfield(app.GridLayout, 'numeric');
            app.gut_r.Editable = 'off';
            app.gut_g = uieditfield(app.GridLayout, 'numeric');
            app.gut_g.Editable = 'off';
            app.gut_b = uieditfield(app.GridLayout, 'numeric');
            app.gut_b.Editable = 'off';
            app.Label_2 = uilabel(app.GridLayout);
            app.Label_2.Text = '@';
            app.Label_3 = uilabel(app.GridLayout);
            app.Label_3.Text = '@';
            app.Label_4 = uilabel(app.GridLayout);
            app.Label_4.Text = '@';
            app.Label_5 = uilabel(app.GridLayout);
            app.Label_5.Text = '@';
            app.Label_6 = uilabel(app.GridLayout);
            app.Label_6.Text = '@';
            app.Label_7 = uilabel(app.GridLayout);
            app.Label_7.Text = '@';
            app.Label_8 = uilabel(app.GridLayout);
            app.Label_8.Text = '@';
            app.XLabel = uilabel(app.GridLayout);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.GridLayout);
            app.YLabel.Text = 'Y';
            app.ZLabel = uilabel(app.GridLayout);
            app.ZLabel.Text = 'Z';
            app.red_x = uieditfield(app.GridLayout, 'numeric');
            app.red_x.Editable = 'off';
            app.red_y = uieditfield(app.GridLayout, 'numeric');
            app.red_y.Editable = 'off';
            app.red_z = uieditfield(app.GridLayout, 'numeric');
            app.red_z.Editable = 'off';
            app.green_x = uieditfield(app.GridLayout, 'numeric');
            app.green_x.Editable = 'off';
            app.green_y = uieditfield(app.GridLayout, 'numeric');
            app.green_y.Editable = 'off';
            app.green_z = uieditfield(app.GridLayout, 'numeric');
            app.green_z.Editable = 'off';
            app.blue_x = uieditfield(app.GridLayout, 'numeric');
            app.blue_x.Editable = 'off';
            app.blue_y = uieditfield(app.GridLayout, 'numeric');
            app.blue_y.Editable = 'off';
            app.blue_z = uieditfield(app.GridLayout, 'numeric');
            app.blue_z.Editable = 'off';
            app.white_x = uieditfield(app.GridLayout, 'numeric');
            app.white_x.Editable = 'off';
            app.white_y = uieditfield(app.GridLayout, 'numeric');
            app.white_y.Editable = 'off';
            app.white_z = uieditfield(app.GridLayout, 'numeric');
            app.white_z.Editable = 'off';
            app.gfp_x = uieditfield(app.GridLayout, 'numeric');
            app.gfp_x.Editable = 'off';
            app.gfp_y = uieditfield(app.GridLayout, 'numeric');
            app.gfp_y.Editable = 'off';
            app.gfp_z = uieditfield(app.GridLayout, 'numeric');
            app.gfp_z.Editable = 'off';
            app.lyso_x = uieditfield(app.GridLayout, 'numeric');
            app.lyso_x.Editable = 'off';
            app.lyso_y = uieditfield(app.GridLayout, 'numeric');
            app.lyso_y.Editable = 'off';
            app.lyso_z = uieditfield(app.GridLayout, 'numeric');
            app.lyso_z.Editable = 'off';
            app.gut_x = uieditfield(app.GridLayout, 'numeric');
            app.gut_x.Editable = 'off';
            app.gut_y = uieditfield(app.GridLayout, 'numeric');
            app.gut_y.Editable = 'off';
            app.gut_z = uieditfield(app.GridLayout, 'numeric');
            app.gut_z.Editable = 'off';
            app.ZSlicesLabel = uilabel(app.UIFigure);
            app.ZSlicesLabel.Text = ' Z Slices';
            app.zSlicesListBox = uilistbox(app.UIFigure);
            app.zSlicesListBox.ValueChangedFcn = createCallbackFcn(app, @zSlicesListBoxValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = channel_selector(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end