classdef histogram_window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        ButtonGroup            matlab.ui.container.ButtonGroup
        dic_lock               matlab.ui.control.Switch
        Switch_6Label          matlab.ui.control.Label
        gfp_lock               matlab.ui.control.Switch
        Switch_5Label          matlab.ui.control.Label
        white_lock             matlab.ui.control.Switch
        Switch_4Label          matlab.ui.control.Label
        blue_lock              matlab.ui.control.Switch
        Switch_3Label          matlab.ui.control.Label
        green_lock             matlab.ui.control.Switch
        Switch_2Label          matlab.ui.control.Label
        red_lock               matlab.ui.control.Switch
        Label                  matlab.ui.control.Label
        best_fit               matlab.ui.control.ToggleButton
        MAXLabel               matlab.ui.control.Label
        MINLabel               matlab.ui.control.Label
        dic_max                matlab.ui.control.Spinner
        dic_min                matlab.ui.control.Spinner
        DICCheckBox            matlab.ui.control.CheckBox
        gfp_max                matlab.ui.control.Spinner
        gfp_min                matlab.ui.control.Spinner
        GFPCheckBox            matlab.ui.control.CheckBox
        white_max              matlab.ui.control.Spinner
        white_min              matlab.ui.control.Spinner
        blue_max               matlab.ui.control.Spinner
        blue_min               matlab.ui.control.Spinner
        green_max              matlab.ui.control.Spinner
        green_min              matlab.ui.control.Spinner
        red_max                matlab.ui.control.Spinner
        red_min                matlab.ui.control.Spinner
        reset                  matlab.ui.control.ToggleButton
        WHITECheckBox          matlab.ui.control.CheckBox
        BLUECheckBox           matlab.ui.control.CheckBox
        GREENCheckBox          matlab.ui.control.CheckBox
        REDCheckBox            matlab.ui.control.CheckBox
        SettingsButtonGroup_2  matlab.ui.container.ButtonGroup
        GAMMALabel             matlab.ui.control.Label
        gamma_knob             matlab.ui.control.Knob
        GuideCheckBox          matlab.ui.control.CheckBox
        HTML                   matlab.ui.control.HTML
        UIAxes                 matlab.ui.control.UIAxes
        ImageAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        wormImg; % Worm image.
        image_gamma; % Main app's gamma var
    end

    methods (Access = private)
        
        function updateimage(app,imagefile)
            
            try
                im = image(app.ImageAxes,imagefile);
                [pixel_count, gray_levels] = imhist(imagefile);
            catch ME
                % If problem reading image, display error message
                uialert(app.UIFigure, ME.message, 'Image Error');
                sprintf('%s %s', mat2str(size(imagefile)), class(imagefile))
                return;
            end  
            
            hold(app.UIAxes,'on')

            % Create histograms based on number of color channels
            switch size(im,3)
                case 1
                    % Plot all histograms with the same data for grayscale
                    histq = histogram(app.UIAxes, gray_levels, 'FaceColor',[0.70 0.70 0.70],'EdgeColor', 'none','FaceAlpha',[0.65]);
                    histr = histogram(app.UIAxes, imagefile, 'FaceColor',[1 0 0],'EdgeColor', 'none','FaceAlpha',[0.25]);
                    histg = histogram(app.UIAxes, imagefile, 'FaceColor',[0 1 0],'EdgeColor', 'none','FaceAlpha',[0.25]);
                    histb = histogram(app.UIAxes, imagefile, 'FaceColor',[0 0 1],'EdgeColor', 'none','FaceAlpha',[0.25]);

                case 3                    
                    % Plot the histograms
                    histq = histogram(app.UIAxes, gray_levels, 'FaceColor',[0.70 0.70 0.70],'EdgeColor', 'none','FaceAlpha',[0.65]);
                    histr = histogram(app.UIAxes, imagefile(:,:,1), 'FaceColor', [1 0 0], 'EdgeColor', 'none','FaceAlpha',[0.25]);
                    histg = histogram(app.UIAxes, imagefile(:,:,2), 'FaceColor', [0 1 0], 'EdgeColor', 'none','FaceAlpha',[0.25]);
                    histb = histogram(app.UIAxes, imagefile(:,:,3), 'FaceColor', [0 0 1], 'EdgeColor', 'none','FaceAlpha',[0.25]);
                    
                otherwise
                    % Error when image is not grayscale or truecolor
                    uialert(app.UIFigure, 'Image must be grayscale or truecolor.', 'Image Error');
                    sprintf('%s %s', mat2str(size(imagefile)), class(imagefile))
                    return;

            end
                
                legend(app.UIAxes, 'Red','Green','Blue','Luminosity')

                % Get largest bin count
                maxq = max(histq.BinCounts);
                maxr = max(histr.BinCounts);
                maxg = max(histg.BinCounts);
                maxb = max(histb.BinCounts);
                maxcount = max([maxr maxg maxb maxq]);
                
                % Set y axes limits based on largest bin count
                app.UIAxes.YLim = [0 maxcount];
                app.UIAxes.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
         
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, wormImg, image_gamma)
            
            app.wormImg = wormImg;
            app.image_gamma = image_gamma;
            
            % Configure image axes
            app.ImageAxes.Visible = 'off';
            app.ImageAxes.Colormap = gray(256);
            axis(app.ImageAxes, 'image');
            
            % Update the image and histograms
            updateimage(app, app.wormImg);
        end

        % Callback function
        function DropDownValueChanged(app, event)
            
            % Update the image and histograms
            updateimage(app, app.DropDown.Value);
        end

        % Callback function
        function LoadButtonPushed(app, event)
               
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               fname = [p f];
               updateimage(app, fname);
            end
        end

        % Value changed function: REDCheckBox
        function REDCheckBoxValueChanged(app, event)
            if app.REDCheckBox.Value
                set( histr, 'Visible', 'on' )
            else
                set( histr, 'Visible', 'off' )
            end
            drawnow;
        end

        % Value changed function: BLUECheckBox
        function BLUECheckBoxValueChanged(app, event)
            if app.BLUECheckBox.Value
                set( histb, 'Visible', 'on' )
            else
                set( histb, 'Visible', 'off' )
            end
            drawnow;
        end

        % Value changed function: GREENCheckBox
        function GREENCheckBoxValueChanged(app, event)
            if app.GREENCheckBox.Value
                set( histg, 'Visible', 'on' )
            else
                set( histg, 'Visible', 'off' )
            end
            drawnow;
        end

        % Value changed function: red_lock
        function red_lockValueChanged(app, event)
            if app.red_lock.Value
                set(app.red_min, 'Enable', 'off')
                set(app.red_max, 'Enable', 'off')
            else
                set(app.red_min, 'Enable', 'on')
                set(app.red_max, 'Enable', 'on')
            end
        end

        % Value changed function: green_lock
        function green_lockValueChanged(app, event)
            if app.green_lock.Value
                set(app.green_min, 'Enable', 'off')
                set(app.green_max, 'Enable', 'off')
            else
                set(app.green_min, 'Enable', 'on')
                set(app.green_max, 'Enable', 'on')
            end
        end

        % Value changed function: blue_lock
        function blue_lockValueChanged(app, event)
            if app.blue_lock.Value
                set(app.blue_min, 'Enable', 'off')
                set(app.blue_max, 'Enable', 'off')
            else
                set(app.blue_min, 'Enable', 'on')
                set(app.blue_max, 'Enable', 'on')
            end
        end

        % Value changed function: white_lock
        function white_lockValueChanged(app, event)
            if app.white_lock.Value
                set(app.white_min, 'Enable', 'off')
                set(app.white_max, 'Enable', 'off')
            else
                set(app.white_min, 'Enable', 'on')
                set(app.white_max, 'Enable', 'on')
            end
        end

        % Value changed function: gfp_lock
        function gfp_lockValueChanged(app, event)
            if app.gfp_lock.Value
                set(app.gfp_min, 'Enable', 'off')
                set(app.gfp_max, 'Enable', 'off')
            else
                set(app.gfp_min, 'Enable', 'on')
                set(app.gfp_max, 'Enable', 'on')
            end
        end

        % Value changed function: dic_lock
        function dic_lockValueChanged(app, event)
            if app.dic_lock.Value
                set(app.dic_min, 'Enable', 'off')
                set(app.dic_max, 'Enable', 'off')
            else
                set(app.dic_min, 'Enable', 'on')
                set(app.dic_max, 'Enable', 'on')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Image Histograms';
            app.ImageAxes = uiaxes(app.UIFigure);
            title(app.ImageAxes, 'Worm Projection')
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = '';
            app.ImageAxes.YTick = [];
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Histogram')
            xlabel(app.UIAxes, 'Intensity')
            ylabel(app.UIAxes, 'Pixels')
            app.HTML = uihtml(app.UIFigure);
            app.HTML.HTMLSource = '<hr>';
            app.SettingsButtonGroup_2 = uibuttongroup(app.UIFigure);
            app.SettingsButtonGroup_2.Title = 'Settings';
            app.GuideCheckBox = uicheckbox(app.SettingsButtonGroup_2);
            app.GuideCheckBox.Tooltip = {'Overlay histogram generated from NeuroPAL ID manual examples.'};
            app.GuideCheckBox.Text = 'Guide';
            app.gamma_knob = uiknob(app.SettingsButtonGroup_2, 'continuous');
            app.gamma_knob.Limits = [0 1];
            app.gamma_knob.MajorTicks = [0 0.5 1];
            app.gamma_knob.Value = 0.5;
            app.GAMMALabel = uilabel(app.SettingsButtonGroup_2);
            app.GAMMALabel.Text = 'GAMMA';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.REDCheckBox = uicheckbox(app.ButtonGroup);
            app.REDCheckBox.ValueChangedFcn = createCallbackFcn(app, @REDCheckBoxValueChanged, true);
            app.REDCheckBox.Text = 'RED';
            app.REDCheckBox.Value = true;
            app.GREENCheckBox = uicheckbox(app.ButtonGroup);
            app.GREENCheckBox.ValueChangedFcn = createCallbackFcn(app, @GREENCheckBoxValueChanged, true);
            app.GREENCheckBox.Text = 'GREEN';
            app.GREENCheckBox.Value = true;
            app.BLUECheckBox = uicheckbox(app.ButtonGroup);
            app.BLUECheckBox.ValueChangedFcn = createCallbackFcn(app, @BLUECheckBoxValueChanged, true);
            app.BLUECheckBox.Text = 'BLUE';
            app.BLUECheckBox.Value = true;
            app.WHITECheckBox = uicheckbox(app.ButtonGroup);
            app.WHITECheckBox.Text = 'WHITE';
            app.WHITECheckBox.Value = true;
            app.reset = uitogglebutton(app.ButtonGroup);
            app.reset.Text = 'RESET';
            app.reset.Value = true;
            app.red_min = uispinner(app.ButtonGroup);
            app.red_min.Step = 0.1;
            app.red_min.Limits = [0 1];
            app.red_min.ValueDisplayFormat = '%.2f';
            app.red_min.Value = 0.5;
            app.red_max = uispinner(app.ButtonGroup);
            app.red_max.Step = 0.1;
            app.red_max.Limits = [0 1];
            app.red_max.ValueDisplayFormat = '%.2f';
            app.red_max.Value = 0.5;
            app.green_min = uispinner(app.ButtonGroup);
            app.green_min.Step = 0.1;
            app.green_min.Limits = [0 1];
            app.green_min.ValueDisplayFormat = '%.2f';
            app.green_min.Value = 0.5;
            app.green_max = uispinner(app.ButtonGroup);
            app.green_max.Step = 0.1;
            app.green_max.Limits = [0 1];
            app.green_max.ValueDisplayFormat = '%.2f';
            app.green_max.Value = 0.5;
            app.blue_min = uispinner(app.ButtonGroup);
            app.blue_min.Step = 0.1;
            app.blue_min.Limits = [0 1];
            app.blue_min.ValueDisplayFormat = '%.2f';
            app.blue_min.Value = 0.5;
            app.blue_max = uispinner(app.ButtonGroup);
            app.blue_max.Step = 0.1;
            app.blue_max.Limits = [0 1];
            app.blue_max.ValueDisplayFormat = '%.2f';
            app.blue_max.Value = 0.5;
            app.white_min = uispinner(app.ButtonGroup);
            app.white_min.Step = 0.1;
            app.white_min.Limits = [0 1];
            app.white_min.ValueDisplayFormat = '%.2f';
            app.white_min.Value = 0.5;
            app.white_max = uispinner(app.ButtonGroup);
            app.white_max.Step = 0.1;
            app.white_max.Limits = [0 1];
            app.white_max.ValueDisplayFormat = '%.2f';
            app.white_max.Value = 0.5;
            app.GFPCheckBox = uicheckbox(app.ButtonGroup);
            app.GFPCheckBox.Text = 'GFP';
            app.GFPCheckBox.Value = true;
            app.gfp_min = uispinner(app.ButtonGroup);
            app.gfp_min.Step = 0.1;
            app.gfp_min.Limits = [0 1];
            app.gfp_min.ValueDisplayFormat = '%.2f';
            app.gfp_min.Value = 0.5;
            app.gfp_max = uispinner(app.ButtonGroup);
            app.gfp_max.Step = 0.1;
            app.gfp_max.Limits = [0 1];
            app.gfp_max.ValueDisplayFormat = '%.2f';
            app.gfp_max.Value = 0.5;
            app.DICCheckBox = uicheckbox(app.ButtonGroup);
            app.DICCheckBox.Text = 'DIC';
            app.DICCheckBox.Value = true;
            app.dic_min = uispinner(app.ButtonGroup);
            app.dic_min.Step = 0.1;
            app.dic_min.Limits = [0 1];
            app.dic_min.ValueDisplayFormat = '%.2f';
            app.dic_min.Value = 0.5;
            app.dic_max = uispinner(app.ButtonGroup);
            app.dic_max.Step = 0.1;
            app.dic_max.Limits = [0 1];
            app.dic_max.ValueDisplayFormat = '%.2f';
            app.dic_max.Value = 0.5;
            app.MINLabel = uilabel(app.ButtonGroup);
            app.MINLabel.Text = 'MIN';
            app.MAXLabel = uilabel(app.ButtonGroup);
            app.MAXLabel.Text = 'MAX';
            app.best_fit = uitogglebutton(app.ButtonGroup);
            app.best_fit.Text = 'Best Fit';
            app.Label = uilabel(app.ButtonGroup);
            app.Label.Text = '';
            app.red_lock = uiswitch(app.ButtonGroup, 'slider');
            app.red_lock.Items = {' ', '🔒'};
            app.red_lock.ValueChangedFcn = createCallbackFcn(app, @red_lockValueChanged, true);
            app.red_lock.Value = ' ';
            app.Switch_2Label = uilabel(app.ButtonGroup);
            app.Switch_2Label.Text = '';
            app.green_lock = uiswitch(app.ButtonGroup, 'slider');
            app.green_lock.Items = {' ', '🔒'};
            app.green_lock.ValueChangedFcn = createCallbackFcn(app, @green_lockValueChanged, true);
            app.green_lock.Value = ' ';
            app.Switch_3Label = uilabel(app.ButtonGroup);
            app.Switch_3Label.Text = '';
            app.blue_lock = uiswitch(app.ButtonGroup, 'slider');
            app.blue_lock.Items = {' ', '🔒'};
            app.blue_lock.ValueChangedFcn = createCallbackFcn(app, @blue_lockValueChanged, true);
            app.blue_lock.Value = ' ';
            app.Switch_4Label = uilabel(app.ButtonGroup);
            app.Switch_4Label.Text = '';
            app.white_lock = uiswitch(app.ButtonGroup, 'slider');
            app.white_lock.Items = {' ', '🔒'};
            app.white_lock.ValueChangedFcn = createCallbackFcn(app, @white_lockValueChanged, true);
            app.white_lock.Value = ' ';
            app.Switch_5Label = uilabel(app.ButtonGroup);
            app.Switch_5Label.Text = '';
            app.gfp_lock = uiswitch(app.ButtonGroup, 'slider');
            app.gfp_lock.Items = {' ', '🔒'};
            app.gfp_lock.ValueChangedFcn = createCallbackFcn(app, @gfp_lockValueChanged, true);
            app.gfp_lock.Value = ' ';
            app.Switch_6Label = uilabel(app.ButtonGroup);
            app.Switch_6Label.Text = '';
            app.dic_lock = uiswitch(app.ButtonGroup, 'slider');
            app.dic_lock.Items = {' ', '🔒'};
            app.dic_lock.ValueChangedFcn = createCallbackFcn(app, @dic_lockValueChanged, true);
            app.dic_lock.Value = ' ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = histogram_window(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end