classdef nwbsave < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SaveasNWBFileUIFigure           matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        AuthorshipTab                   matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        RelatedPublications             matlab.ui.control.EditField
        RelatedPublicationsLabel        matlab.ui.control.Label
        DatadescriptionTextArea         matlab.ui.control.TextArea
        DatadescriptionTextAreaLabel    matlab.ui.control.Label
        InstitutionalAffiliationEditField  matlab.ui.control.EditField
        InstitutionalAffiliationEditFieldLabel  matlab.ui.control.Label
        AuthorLaboratoryEditField       matlab.ui.control.EditField
        AuthorLaboratoryEditFieldLabel  matlab.ui.control.Label
        DevicesTab                      matlab.ui.container.Tab
        GridLayout4_2                   matlab.ui.container.GridLayout
        Panel_18                        matlab.ui.container.Panel
        GridLayout13                    matlab.ui.container.GridLayout
        EditButton                      matlab.ui.control.Button
        RemoveButton                    matlab.ui.control.Button
        DeviceListListBox               matlab.ui.control.ListBox
        DeviceListListBoxLabel          matlab.ui.control.Label
        AddDeviceButton                 matlab.ui.control.Button
        DeviceDescriptionTextArea       matlab.ui.control.TextArea
        DeviceDescriptionTextAreaLabel  matlab.ui.control.Label
        DeviceManufacturerEditField     matlab.ui.control.EditField
        DeviceManufacturerEditFieldLabel  matlab.ui.control.Label
        DeviceNameEditField             matlab.ui.control.EditField
        DeviceNameEditFieldLabel        matlab.ui.control.Label
        Tab                             matlab.ui.container.Tab
        GridLayout15                    matlab.ui.container.GridLayout
        Panel_20                        matlab.ui.container.Panel
        GridLayout16                    matlab.ui.container.GridLayout
        RemoveButton_2                  matlab.ui.control.Button
        EditButton_2                    matlab.ui.control.Button
        AddOpticalChannelButton         matlab.ui.control.Button
        GridLayout6                     matlab.ui.container.GridLayout
        UITable                         matlab.ui.control.Table
        OpticalChannelReferencesLabel   matlab.ui.control.Label
        WavelengthTextArea              matlab.ui.control.TextArea
        WavelengthTextAreaLabel         matlab.ui.control.Label
        FilterEditField                 matlab.ui.control.EditField
        FilterEditFieldLabel            matlab.ui.control.Label
        FluorophoreEditField            matlab.ui.control.EditField
        FluorophoreEditFieldLabel       matlab.ui.control.Label
        DataTab                         matlab.ui.container.Tab
        GridLayout7                     matlab.ui.container.GridLayout
        SubjectInfoPanel                matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        StrainEditField_2               matlab.ui.control.EditField
        StrainEditField_2Label          matlab.ui.control.Label
        Panel_16                        matlab.ui.container.Panel
        GridLayout11_3                  matlab.ui.container.GridLayout
        CultivationtempCEditField_3     matlab.ui.control.NumericEditField
        CultivationtempCEditField_3Label  matlab.ui.control.Label
        Panel_14                        matlab.ui.container.Panel
        GridLayout11                    matlab.ui.container.GridLayout
        LocationDropDown                matlab.ui.control.DropDown
        LocationDropDownLabel           matlab.ui.control.Label
        Panel_13                        matlab.ui.container.Panel
        GridLayout10_2                  matlab.ui.container.GridLayout
        AgeDropDown                     matlab.ui.control.DropDown
        AgeDropDownLabel                matlab.ui.control.Label
        Panel_12                        matlab.ui.container.Panel
        GridLayout10                    matlab.ui.container.GridLayout
        SexDropDown                     matlab.ui.control.DropDown
        SexDropDownLabel                matlab.ui.control.Label
        TabGroup2                       matlab.ui.container.TabGroup
        NeuroPALVolumeTab               matlab.ui.container.Tab
        GridLayout12                    matlab.ui.container.GridLayout
        Panel_19                        matlab.ui.container.Panel
        GridLayout14                    matlab.ui.container.GridLayout
        EditField                       matlab.ui.control.EditField
        NotesLabel                      matlab.ui.control.Label
        DeviceDropDown                  matlab.ui.control.DropDown
        DeviceDropDownLabel             matlab.ui.control.Label
        SegmentationTab                 matlab.ui.container.Tab
        GridLayout17                    matlab.ui.container.GridLayout
        Panel_21                        matlab.ui.container.Panel
        GridLayout14_3                  matlab.ui.container.GridLayout
        EditField_5                     matlab.ui.control.EditField
        NotesLabel_4                    matlab.ui.control.Label
        NeuralActivityTab               matlab.ui.container.Tab
        GridLayout17_2                  matlab.ui.container.GridLayout
        Panel_22                        matlab.ui.container.Panel
        GridLayout14_4                  matlab.ui.container.GridLayout
        EditField_6                     matlab.ui.control.EditField
        NotesLabel_5                    matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        Panel_5                         matlab.ui.container.Panel
        GridLayout3_2                   matlab.ui.container.GridLayout
        CustomFileName                  matlab.ui.control.EditField
        CustomFileNameLabel             matlab.ui.control.Label
        CheckBox                        matlab.ui.control.CheckBox
        CancelButton                    matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        Panel_2                         matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        Hyperlink                       matlab.ui.control.Hyperlink
        OpenDANDIaftersavingCheckBox    matlab.ui.control.CheckBox
        NWBCompatibleDataLabel          matlab.ui.control.Label
        Tree                            matlab.ui.container.CheckBoxTree
        NeuroPALImageNode               matlab.ui.container.TreeNode
        NeuronIDsNode                   matlab.ui.container.TreeNode
        NeuronActivityNode              matlab.ui.container.TreeNode
        NeuronSegmentationsNode         matlab.ui.container.TreeNode
    end

    
    properties (Access = public)
        parent_app;
        image_neurons;
        neuron_activity_by_name;
        image_file;
        devices;
        opt_chans;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parent_app, image_neurons, neuron_activity, image_file)
            app.parent_app = parent_app;
            app.image_neurons = image_neurons;
            app.neuron_activity_by_name = neuron_activity;
            app.image_file = image_file;
            GUI_prefs = Program.GUIPreferences.instance();

            if boolean(size(app.image_neurons.neurons,2)>0)
                app.Tree.CheckedNodes(end+1) = app.NeuronIDsNode;
            end

            if ~strcmp(app.parent_app.checkFileExistence(GUI_prefs.trace_path),'False')
                app.Tree.CheckedNodes(end+1) = app.GCaMPVideoNode;
            end

            if ~isempty(app.neuron_activity_by_name)
                app.Tree.CheckedNodes(end+1) = app.NeuronActivityNode;
            end

            if ~strcmp(app.parent_app.checkFileExistence([GUI_prefs.trace_path,'annotations.h5']),'False')
                app.Tree.CheckedNodes(end+1) = app.GCaMPAnnotationsNode;
            end

            if ~strcmp(app.parent_app.checkFileExistence(GUI_prefs.seg_path),'False')
                app.Tree.CheckedNodes(end+1) = app.NeuronSegmentationsNode;
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            nwb_data = [];
            GUI_prefs = Program.GUIPreferences.instance();

            nwb_data.OpenDandi = app.OpenDANDIaftersavingCheckBox.Value;
            nwb_data.author = app.AuthorLaboratoryEditField.Value;
            nwb_data.institution = app.InstitutionalAffiliationEditField.Value;
            nwb_data.description = app.DatadescriptionTextArea.Value;
            nwb_data.related_pubs = app.RelatedPublications.Value;
            nwb_data.image_path = GUI_prefs.image_dir;
            nwb_data.mat_file = app.image_file;
            nwb_data.id_bool = string(boolean(size(app.image_neurons.neurons,2)>0));
            nwb_data.csv_bool = app.parent_app.checkFileExistence([GUI_prefs.image_dir,'data.csv']);
            nwb_data.gcamp_bool = app.parent_app.checkFileExistence(GUI_prefs.trace_path);
            nwb_data.annotation_bool = app.parent_app.checkFileExistence([GUI_prefs.trace_path,'annotations.h5']);
            nwb_data.segmentation_bool = app.parent_app.checkFileExistence(GUI_prefs.seg_path);
            nwb_data.proceed = 1;
            nwb_data.devices = app.devices;

            if ~isempty(app.neuron_activity_by_name)
                nwb_data.activity_bool = py.dict(app.neuron_activity_by_name);
            else
                nwb_data.activity_bool = 'False';
            end

            if ~isempty(app.DeviceNameEditField.Value)
                nwb_data.device_name = app.DeviceNameEditField.Value;
            end

            if ~isempty(app.DeviceManufacturerEditField.Value)
                nwb_data.manufacturer = app.DeviceManufacturerEditField.Value;
            end

            if ~isempty(app.DeviceDescriptionTextArea.Value)
                nwb_data.device_description = app.DeviceDescriptionTextArea.Value;
            end

            if ~isempty(app.UITable.Data)
                nwb_data.device_channels = app.UITable.Data;
            end

            if ~isempty(app.CustomFileName.Value)
                nwb_data.filename = app.CustomFileName.Value;
            end

            app.parent_app.process_nwb_data(nwb_data);
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            nwb_data = [];
            nwb_data.proceed = 0;
            app.parent_app.process_nwb_data(nwb_data);
            delete(app)
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            if ~app.CheckBox.Value
                set(app.CustomFileNameLabel,'Visible','off');
                set(app.CustomFileName,'Visible','on');
            else
                set(app.CustomFileNameLabel,'Visible','on');
                set(app.CustomFileName,'Visible','off');
            end
        end

        % Callback function
        function ButtonPushed(app, event)
            app.UITable.Data(end+1) = [app.F1,app.F2,app.F3];
        end

        % Button pushed function: AddDeviceButton
        function AddDeviceButtonPushed(app, event)
            new_device = {app.DeviceNameEditField.Value, app.DeviceManufacturerEditField.Value, app.DeviceDescriptionTextArea.Value};
            app.DeviceNameEditField.Value = '';
            app.DeviceManufacturerEditField.Value = '';
            app.DeviceDescriptionTextArea.Value = '';
        
            % Check if app.devices is empty
            if isempty(app.devices)
                % Initialize app.devices with the new device
                app.devices = {new_device};
            else
                % Check if app.devices is not empty and has at least one column
                if size(app.devices, 2) >= 1
                    % Remove any existing device with the same name
                    app.devices = app.devices(~strcmp(app.devices(:,1), new_device{1}), :);
                end
                % Add new device
                app.devices{end+1, 1} = new_device;
            end
        
            app.DeviceListListBox.Items{end+1} = new_device{1};
            app.DeviceDropDown.ItemsData = app.DeviceListListBox.ItemsData;
            app.DeviceDropDown.Items = app.DeviceListListBox.Items;
        end

        % Button pushed function: EditButton
        function EditButtonPushed(app, event)
            selectedDeviceName = app.DeviceListListBox.Value;
            disp(['Selected device name: ', selectedDeviceName]);  % Diagnostic message
        
            if ~isempty(selectedDeviceName)
                for n = 1:size(app.devices, 1)
                    % Extract the device details from the cell array
                    deviceDetails = app.devices{n};
                    
                    % Check if deviceDetails is a cell and extract the device name
                    if iscell(deviceDetails) && length(deviceDetails) >= 1
                        deviceName = deviceDetails{1};
                        disp(['Checking device: ', deviceName]);  % Diagnostic message
        
                        if strcmp(deviceName, selectedDeviceName)
                            % Update the fields with the device details
                            app.DeviceNameEditField.Value = deviceName;
                            if length(deviceDetails) >= 2
                                app.DeviceManufacturerEditField.Value = deviceDetails{2};
                            end
                            if length(deviceDetails) >= 3
                                app.DeviceDescriptionTextArea.Value = deviceDetails{3};
                            end
                            disp('Device found and fields updated.');  % Diagnostic message
                            break;  % Break the loop once the matching device is found
                        end
                    end
                end
            else
                disp('No device selected.');  % Diagnostic message
            end
            
            app.DeviceDropDown.ItemsData = app.DeviceListListBox.ItemsData;
            app.DeviceDropDown.Items = app.DeviceListListBox.Items;
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            selectedDeviceName = app.DeviceListListBox.Value;
        
            if ~isempty(selectedDeviceName)
                % Get the current list of device names
                deviceNames = app.DeviceListListBox.Items;
        
                % Find the index of the selected device
                selectedIndex = find(strcmp(deviceNames, selectedDeviceName));
        
                % Remove the selected device name from the list
                deviceNames(selectedIndex) = [];
        
                % Update the list box
                app.DeviceListListBox.Items = deviceNames;
        
                % Remove the corresponding device from app.devices
                app.devices(selectedIndex) = [];
        
                % Clear the selection
                app.DeviceListListBox.Value = {};
            end

            app.DeviceDropDown.ItemsData = app.DeviceListListBox.ItemsData;
            app.DeviceDropDown.Items = app.DeviceListListBox.Items;
        end

        % Button pushed function: AddOpticalChannelButton
        function AddOpticalChannelButtonPushed(app, event)
            new_opt = {app.FluorophoreEditField.Value, app.FilterEditField.Value, app.WavelengthTextArea.Value};
            app.FluorophoreEditField.Value = '';
            app.FilterEditField.Value = '';
            app.WavelengthTextArea.Value = '';
        
            % Check if app.devices is empty
            if isempty(app.opt_chans_chans)
                % Initialize app.devices with the new device
                app.opt_chans = {new_opt};
            else
                % Check if app.devices is not empty and has at least one column
                if size(app.opt_chans, 2) >= 1
                    % Remove any existing device with the same name
                    app.opt_chans = app.opt_chans(~strcmp(app.opt_chans(:,1), new_opt{1}), :);
                end
                % Add new device
                app.opt_chans{end+1, 1} = new_opt;
            end
        
            app.DeviceListListBox.Items{end+1} = new_opt{1};
            app.opt_chansicalChannelDropDown.ItemsData = app.DeviceListListBox.ItemsData;
            app.opt_chansicalChannelDropDown.Items = app.DeviceListListBox.Items;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SaveasNWBFileUIFigure = uifigure('Visible', 'off');
            app.SaveasNWBFileUIFigure.Name = 'Save as NWB File';
            app.GridLayout = uigridlayout(app.SaveasNWBFileUIFigure);
            app.Tree = uitree(app.GridLayout, 'checkbox');
            app.Tree.Enable = 'off';
            app.NeuroPALImageNode = uitreenode(app.Tree);
            app.NeuroPALImageNode.Text = 'NeuroPAL Image';
            app.NeuronIDsNode = uitreenode(app.Tree);
            app.NeuronIDsNode.Text = 'Neuron IDs';
            app.NeuronActivityNode = uitreenode(app.Tree);
            app.NeuronActivityNode.Text = 'Neuron Activity';
            app.NeuronSegmentationsNode = uitreenode(app.Tree);
            app.NeuronSegmentationsNode.Text = 'Neuron Segmentations';
            % Assign Checked Nodes
            app.Tree.CheckedNodes = [app.NeuroPALImageNode];
            app.NWBCompatibleDataLabel = uilabel(app.GridLayout);
            app.NWBCompatibleDataLabel.Text = 'NWB-Compatible Data';
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.Panel_2 = uipanel(app.GridLayout2);
            app.GridLayout3 = uigridlayout(app.Panel_2);
            app.OpenDANDIaftersavingCheckBox = uicheckbox(app.GridLayout3);
            app.OpenDANDIaftersavingCheckBox.Text = 'Open DANDI after saving.';
            app.Hyperlink = uihyperlink(app.GridLayout3);
            app.Hyperlink.URL = 'https://www.dandiarchive.org/';
            app.Hyperlink.Text = '(?)';
            app.SaveButton = uibutton(app.GridLayout2, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.CancelButton = uibutton(app.GridLayout2, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.Panel_5 = uipanel(app.GridLayout2);
            app.GridLayout3_2 = uigridlayout(app.Panel_5);
            app.CheckBox = uicheckbox(app.GridLayout3_2);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CustomFileNameLabel = uilabel(app.GridLayout3_2);
            app.CustomFileNameLabel.Text = ' Custom File Name.';
            app.CustomFileName = uieditfield(app.GridLayout3_2, 'text');
            app.CustomFileName.Visible = 'off';
            app.CustomFileName.Placeholder = 'wormyworm.nwb';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.AuthorshipTab = uitab(app.TabGroup);
            app.AuthorshipTab.Title = 'Authorship';
            app.GridLayout4 = uigridlayout(app.AuthorshipTab);
            app.AuthorLaboratoryEditFieldLabel = uilabel(app.GridLayout4);
            app.AuthorLaboratoryEditFieldLabel.Text = 'Author / Laboratory';
            app.AuthorLaboratoryEditField = uieditfield(app.GridLayout4, 'text');
            app.AuthorLaboratoryEditField.Placeholder = '(e.g. "Lorem Ipsum Labs")';
            app.InstitutionalAffiliationEditFieldLabel = uilabel(app.GridLayout4);
            app.InstitutionalAffiliationEditFieldLabel.Text = 'Institutional Affiliation';
            app.InstitutionalAffiliationEditField = uieditfield(app.GridLayout4, 'text');
            app.InstitutionalAffiliationEditField.Placeholder = '(e.g. "Sample University")';
            app.DatadescriptionTextAreaLabel = uilabel(app.GridLayout4);
            app.DatadescriptionTextAreaLabel.Text = 'Data description';
            app.DatadescriptionTextArea = uitextarea(app.GridLayout4);
            app.DatadescriptionTextArea.Placeholder = '(e.g. description of experiment, notes, etc.)';
            app.RelatedPublicationsLabel = uilabel(app.GridLayout4);
            app.RelatedPublicationsLabel.Text = 'Related Publications';
            app.RelatedPublications = uieditfield(app.GridLayout4, 'text');
            app.RelatedPublications.Placeholder = '(e.g. a DOI, a link, etc.)';
            app.DevicesTab = uitab(app.TabGroup);
            app.DevicesTab.Title = 'Devices';
            app.GridLayout4_2 = uigridlayout(app.DevicesTab);
            app.DeviceNameEditFieldLabel = uilabel(app.GridLayout4_2);
            app.DeviceNameEditFieldLabel.Text = 'Device Name';
            app.DeviceNameEditField = uieditfield(app.GridLayout4_2, 'text');
            app.DeviceNameEditField.Placeholder = '(e.g. "Spinning Disk Confocal")';
            app.DeviceManufacturerEditFieldLabel = uilabel(app.GridLayout4_2);
            app.DeviceManufacturerEditFieldLabel.Text = 'Device Manufacturer';
            app.DeviceManufacturerEditField = uieditfield(app.GridLayout4_2, 'text');
            app.DeviceManufacturerEditField.Placeholder = '(e.g. Nikon)';
            app.DeviceDescriptionTextAreaLabel = uilabel(app.GridLayout4_2);
            app.DeviceDescriptionTextAreaLabel.Text = 'Device Description';
            app.DeviceDescriptionTextArea = uitextarea(app.GridLayout4_2);
            app.DeviceDescriptionTextArea.Placeholder = '(e.g. Ti-e 60x Objective, 1.2 NA Nikon CFI Plan Apochromat VC 60XC WI)';
            app.AddDeviceButton = uibutton(app.GridLayout4_2, 'push');
            app.AddDeviceButton.ButtonPushedFcn = createCallbackFcn(app, @AddDeviceButtonPushed, true);
            app.AddDeviceButton.Text = 'Add Device';
            app.DeviceListListBoxLabel = uilabel(app.GridLayout4_2);
            app.DeviceListListBoxLabel.Text = 'Device List';
            app.DeviceListListBox = uilistbox(app.GridLayout4_2);
            app.DeviceListListBox.Items = {};
            app.DeviceListListBox.Value = {};
            app.Panel_18 = uipanel(app.GridLayout4_2);
            app.GridLayout13 = uigridlayout(app.Panel_18);
            app.RemoveButton = uibutton(app.GridLayout13, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = 'Remove';
            app.EditButton = uibutton(app.GridLayout13, 'push');
            app.EditButton.ButtonPushedFcn = createCallbackFcn(app, @EditButtonPushed, true);
            app.EditButton.Text = 'Edit';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.GridLayout15 = uigridlayout(app.Tab);
            app.FluorophoreEditFieldLabel = uilabel(app.GridLayout15);
            app.FluorophoreEditFieldLabel.Text = 'Fluorophore';
            app.FluorophoreEditField = uieditfield(app.GridLayout15, 'text');
            app.FluorophoreEditField.Placeholder = '(e.g. "mTagBFP2")';
            app.FilterEditFieldLabel = uilabel(app.GridLayout15);
            app.FilterEditFieldLabel.Text = 'Filter';
            app.FilterEditField = uieditfield(app.GridLayout15, 'text');
            app.FilterEditField.Placeholder = '(e.g. "Semrock FF01-445/45-25 Brightline")';
            app.WavelengthTextAreaLabel = uilabel(app.GridLayout15);
            app.WavelengthTextAreaLabel.Text = 'Wavelength';
            app.WavelengthTextArea = uitextarea(app.GridLayout15);
            app.WavelengthTextArea.Placeholder = '(e.g. 405-445-45m)';
            app.GridLayout6 = uigridlayout(app.GridLayout15);
            app.OpticalChannelReferencesLabel = uilabel(app.GridLayout6);
            app.OpticalChannelReferencesLabel.Text = 'Optical Channel References';
            app.UITable = uitable(app.GridLayout6);
            app.UITable.ColumnName = {'Protein'; 'Filter'; 'Wavelength'};
            app.UITable.RowName = {};
            app.UITable.SelectionType = 'row';
            app.UITable.ColumnEditable = true;
            app.AddOpticalChannelButton = uibutton(app.GridLayout15, 'push');
            app.AddOpticalChannelButton.ButtonPushedFcn = createCallbackFcn(app, @AddOpticalChannelButtonPushed, true);
            app.AddOpticalChannelButton.Text = 'Add Optical Channel';
            app.Panel_20 = uipanel(app.GridLayout15);
            app.GridLayout16 = uigridlayout(app.Panel_20);
            app.EditButton_2 = uibutton(app.GridLayout16, 'push');
            app.EditButton_2.Text = 'Edit';
            app.RemoveButton_2 = uibutton(app.GridLayout16, 'push');
            app.RemoveButton_2.Text = 'Remove';
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.GridLayout7 = uigridlayout(app.DataTab);
            app.TabGroup2 = uitabgroup(app.GridLayout7);
            app.NeuroPALVolumeTab = uitab(app.TabGroup2);
            app.NeuroPALVolumeTab.Title = 'NeuroPAL Volume';
            app.GridLayout12 = uigridlayout(app.NeuroPALVolumeTab);
            app.DeviceDropDownLabel = uilabel(app.GridLayout12);
            app.DeviceDropDownLabel.Text = 'Device';
            app.DeviceDropDown = uidropdown(app.GridLayout12);
            app.DeviceDropDown.Items = {};
            app.DeviceDropDown.Placeholder = 'Select Device';
            app.DeviceDropDown.Value = {};
            app.Panel_19 = uipanel(app.GridLayout12);
            app.GridLayout14 = uigridlayout(app.Panel_19);
            app.NotesLabel = uilabel(app.GridLayout14);
            app.NotesLabel.Text = 'Notes';
            app.EditField = uieditfield(app.GridLayout14, 'text');
            app.SegmentationTab = uitab(app.TabGroup2);
            app.SegmentationTab.Title = 'Segmentation';
            app.GridLayout17 = uigridlayout(app.SegmentationTab);
            app.Panel_21 = uipanel(app.GridLayout17);
            app.GridLayout14_3 = uigridlayout(app.Panel_21);
            app.NotesLabel_4 = uilabel(app.GridLayout14_3);
            app.NotesLabel_4.Text = 'Notes';
            app.EditField_5 = uieditfield(app.GridLayout14_3, 'text');
            app.NeuralActivityTab = uitab(app.TabGroup2);
            app.NeuralActivityTab.Title = 'Neural Activity';
            app.GridLayout17_2 = uigridlayout(app.NeuralActivityTab);
            app.Panel_22 = uipanel(app.GridLayout17_2);
            app.GridLayout14_4 = uigridlayout(app.Panel_22);
            app.NotesLabel_5 = uilabel(app.GridLayout14_4);
            app.NotesLabel_5.Text = 'Notes';
            app.EditField_6 = uieditfield(app.GridLayout14_4, 'text');
            app.SubjectInfoPanel = uipanel(app.GridLayout7);
            app.SubjectInfoPanel.Title = 'Subject Info';
            app.GridLayout8 = uigridlayout(app.SubjectInfoPanel);
            app.Panel_12 = uipanel(app.GridLayout8);
            app.GridLayout10 = uigridlayout(app.Panel_12);
            app.SexDropDownLabel = uilabel(app.GridLayout10);
            app.SexDropDownLabel.Text = 'Sex';
            app.SexDropDown = uidropdown(app.GridLayout10);
            app.SexDropDown.Items = {'XX', 'XO'};
            app.SexDropDown.Value = 'XX';
            app.Panel_13 = uipanel(app.GridLayout8);
            app.GridLayout10_2 = uigridlayout(app.Panel_13);
            app.AgeDropDownLabel = uilabel(app.GridLayout10_2);
            app.AgeDropDownLabel.Text = 'Age';
            app.AgeDropDown = uidropdown(app.GridLayout10_2);
            app.AgeDropDown.Items = {'Adult', 'L4', 'L3', 'L2', 'L1', '3-Fold'};
            app.AgeDropDown.Value = 'Adult';
            app.Panel_14 = uipanel(app.GridLayout8);
            app.GridLayout11 = uigridlayout(app.Panel_14);
            app.LocationDropDownLabel = uilabel(app.GridLayout11);
            app.LocationDropDownLabel.Text = 'Location';
            app.LocationDropDown = uidropdown(app.GridLayout11);
            app.LocationDropDown.Items = {'Whole Worm', 'Head', 'Midbody', 'Anterior Midbody', 'Central Midbody', 'Posterior Midbody', 'Tail'};
            app.LocationDropDown.Value = 'Whole Worm';
            app.Panel_16 = uipanel(app.GridLayout8);
            app.GridLayout11_3 = uigridlayout(app.Panel_16);
            app.CultivationtempCEditField_3Label = uilabel(app.GridLayout11_3);
            app.CultivationtempCEditField_3Label.Text = 'Cultivation temp (°C)';
            app.CultivationtempCEditField_3 = uieditfield(app.GridLayout11_3, 'numeric');
            app.StrainEditField_2Label = uilabel(app.GridLayout8);
            app.StrainEditField_2Label.Text = 'Strain';
            app.StrainEditField_2 = uieditfield(app.GridLayout8, 'text');
            % Show the figure after all components are created
            app.SaveasNWBFileUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = nwbsave(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SaveasNWBFileUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SaveasNWBFileUIFigure)
        end
    end
end