classdef trace_window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NeuroPAL_IDTraceWindowUIFigure  matlab.ui.Figure
        IDCurrentFramePanel             matlab.ui.container.Panel
        GridLayout21                    matlab.ui.container.GridLayout
        AutoFillOptionsPanel            matlab.ui.container.Panel
        GridLayout26                    matlab.ui.container.GridLayout
        RunAutoSegmentationButton       matlab.ui.control.Button
        Importfromh5fileButton          matlab.ui.control.Button
        LoadfromclosestFrameButton      matlab.ui.control.Button
        MoveNeuronPanel                 matlab.ui.container.Panel
        GridLayout25                    matlab.ui.container.GridLayout
        Button_4                        matlab.ui.control.Button
        Button_5                        matlab.ui.control.Button
        Button_3                        matlab.ui.control.Button
        Button_2                        matlab.ui.control.Button
        NeuronInformationPanel          matlab.ui.container.Panel
        GridLayout24                    matlab.ui.container.GridLayout
        Button2                         matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        NeuronNameEditField             matlab.ui.control.EditField
        NeuronNameEditFieldLabel        matlab.ui.control.Label
        NeuronIndexPanel                matlab.ui.container.Panel
        GridLayout23                    matlab.ui.container.GridLayout
        UITable                         matlab.ui.control.Table
        SettingsPanel_3                 matlab.ui.container.Panel
        GridLayout22                    matlab.ui.container.GridLayout
        pixelsatatimeLabel_2            matlab.ui.control.Label
        MoveEditField_3                 matlab.ui.control.NumericEditField
        MoveEditField_3Label            matlab.ui.control.Label
        SaveNeuronIDsButton             matlab.ui.control.Button
        OnclickDropDown                 matlab.ui.control.DropDown
        OnclickLabel                    matlab.ui.control.Label
        AdjustAlignmentPanel            matlab.ui.container.Panel
        GridLayout15                    matlab.ui.container.GridLayout
        SelectionButtonGroup            matlab.ui.container.ButtonGroup
        MoveVolumeNeuronsButton         matlab.ui.control.RadioButton
        MoveVideoButton                 matlab.ui.control.RadioButton
        MoveNeuronsButton               matlab.ui.control.RadioButton
        MovePanel                       matlab.ui.container.Panel
        GridLayout16                    matlab.ui.container.GridLayout
        Panel_15                        matlab.ui.container.Panel
        GridLayout27                    matlab.ui.container.GridLayout
        Left                            matlab.ui.control.Button
        Up                              matlab.ui.control.Button
        Right                           matlab.ui.control.Button
        Down                            matlab.ui.control.Button
        CCW                             matlab.ui.control.Button
        CW                              matlab.ui.control.Button
        Panel_13                        matlab.ui.container.Panel
        GridLayout18                    matlab.ui.container.GridLayout
        MoveLabel                       matlab.ui.control.Label
        MoveEditField_2                 matlab.ui.control.NumericEditField
        pixelsatatimeLabel              matlab.ui.control.Label
        CenterHorizontallyButton        matlab.ui.control.Button
        CenterVerticallyButton          matlab.ui.control.Button
        FlipVerticallyButton            matlab.ui.control.Button
        FlipHorizontallyButton          matlab.ui.control.Button
        ResetTransformButton            matlab.ui.control.Button
        SaveResultButton                matlab.ui.control.Button
        ResetMoveButton                 matlab.ui.control.Button
        TransformPanel                  matlab.ui.container.Panel
        GridLayout17                    matlab.ui.container.GridLayout
        ScaleEditField                  matlab.ui.control.NumericEditField
        ScaleEditFieldLabel             matlab.ui.control.Label
        WidthSpinner                    matlab.ui.control.Spinner
        WidthSpinnerLabel               matlab.ui.control.Label
        HeightSpinner                   matlab.ui.control.Spinner
        HeightSpinnerLabel              matlab.ui.control.Label
        GridLayout6                     matlab.ui.container.GridLayout
        Panel_14                        matlab.ui.container.Panel
        GridLayout20                    matlab.ui.container.GridLayout
        Slider                          matlab.ui.control.Slider
        ClicktoselectvideofileButton    matlab.ui.control.Button
        NeuroPALVolumePanel             matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        Npal_Axes                       matlab.ui.control.UIAxes
        TabGroup                        matlab.ui.container.TabGroup
        VideoControlsTab                matlab.ui.container.Tab
        Panel_4                         matlab.ui.container.Panel
        GridLayout12                    matlab.ui.container.GridLayout
        Panel_6                         matlab.ui.container.Panel
        GridLayout29                    matlab.ui.container.GridLayout
        FinishIDButton                  matlab.ui.control.Button
        SaveResultsButton_3             matlab.ui.control.Button
        AdjustAlignmentButton_3         matlab.ui.control.Button
        RecommendFramesButton_2         matlab.ui.control.Button
        IDCurrentFrameButton            matlab.ui.control.Button
        VideoPlayback                   matlab.ui.control.Slider
        Panel_8                         matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        BookmarksPanel                  matlab.ui.container.Panel
        GridLayout13                    matlab.ui.container.GridLayout
        ListBox                         matlab.ui.control.ListBox
        SetbookmarkButton               matlab.ui.control.Button
        Panel_11                        matlab.ui.container.Panel
        GridLayout9                     matlab.ui.container.GridLayout
        ForwardButton                   matlab.ui.control.Button
        ReverseButton                   matlab.ui.control.Button
        PlayButton                      matlab.ui.control.StateButton
        SettingsPanel                   matlab.ui.container.Panel
        GridLayout10                    matlab.ui.container.GridLayout
        framesLabel                     matlab.ui.control.Label
        ChunkSize                       matlab.ui.control.NumericEditField
        ChunkSizeEditFieldLabel         matlab.ui.control.Label
        FramerateLabel                  matlab.ui.control.Label
        fpsLabel                        matlab.ui.control.Label
        framerate                       matlab.ui.control.NumericEditField
        SkipToolPanel                   matlab.ui.container.Panel
        GridLayout11                    matlab.ui.container.GridLayout
        JumptoLabel                     matlab.ui.control.Label
        EditField                       matlab.ui.control.NumericEditField
        DropDown                        matlab.ui.control.DropDown
        Panel_5                         matlab.ui.container.Panel
        GridLayout28                    matlab.ui.container.GridLayout
        RunTracerButton                 matlab.ui.control.Button
        RUNTRACKERButton                matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        BasicSettingsTab                matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        ChannelViewerPanel              matlab.ui.container.Panel
        GridLayout19                    matlab.ui.container.GridLayout
        GutCheckBox                     matlab.ui.control.CheckBox
        LysosomeCheckbox                matlab.ui.control.CheckBox
        GFPCheckbox                     matlab.ui.control.CheckBox
        WCheckbox                       matlab.ui.control.CheckBox
        BCheckbox                       matlab.ui.control.CheckBox
        GCheckbox                       matlab.ui.control.CheckBox
        RCheckbox                       matlab.ui.control.CheckBox
        Panel_12                        matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        l_n_mode                        matlab.ui.control.DropDown
        MethodtouseforcalculatingNLabel  matlab.ui.control.Label
        NmodeLabel                      matlab.ui.control.Label
        l_n                             matlab.ui.control.NumericEditField
        EditField4Label                 matlab.ui.control.Label
        NLabel                          matlab.ui.control.Label
        LRCoeffLabel                    matlab.ui.control.Label
        lr_coeff                        matlab.ui.control.NumericEditField
        EditField6Label                 matlab.ui.control.Label
        nn_max                          matlab.ui.control.NumericEditField
        EditFieldLabel_2                matlab.ui.control.Label
        nn_maxLabel                     matlab.ui.control.Label
        UseGPULabel                     matlab.ui.control.Label
        use_gpu                         matlab.ui.control.DropDown
        SetsdevicetoGPUandenablesCUDAuseDropDownLabel  matlab.ui.control.Label
        MotionPanel                     matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        Slider_4                        matlab.ui.control.Slider
        EnableManualSettingsCheckBox    matlab.ui.control.CheckBox
        AdvancedSettingsTab             matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        n_chunksLabel                   matlab.ui.control.Label
        n_chunks                        matlab.ui.control.NumericEditField
        NumberofstepstodividetheforwardpassintoEditFieldLabel  matlab.ui.control.Label
        n_epoch_dLabel                  matlab.ui.control.Label
        n_epochLabel                    matlab.ui.control.Label
        n_epoch_d                       matlab.ui.control.NumericEditField
        EditField2_2Label               matlab.ui.control.Label
        n_epoch                         matlab.ui.control.NumericEditField
        NumberofiterationsforimageregistrationLREditFieldLabel  matlab.ui.control.Label
        HoveroverlabelsforfurtherdetailsorguidanceLabel  matlab.ui.control.Label
        ZCompensatorLabel               matlab.ui.control.Label
        LRFloorLabel                    matlab.ui.control.Label
        dLabel                          matlab.ui.control.Label
        tLabel                          matlab.ui.control.Label
        clip_gradLabel                  matlab.ui.control.Label
        z_comp                          matlab.ui.control.NumericEditField
        Multiplygradientsinthezaxisby1z_compensatorEditFieldLabel  matlab.ui.control.Label
        lr_floor                        matlab.ui.control.NumericEditField
        Minimumvalueforinitiallearningratedefault002EditFieldLabel  matlab.ui.control.Label
        l_d                             matlab.ui.control.NumericEditField
        EditField3Label                 matlab.ui.control.Label
        l_t                             matlab.ui.control.NumericEditField
        EditField2Label                 matlab.ui.control.Label
        clip_grad                       matlab.ui.control.NumericEditField
        EditFieldLabel                  matlab.ui.control.Label
        CreditTab                       matlab.ui.container.Tab
        GridLayout14                    matlab.ui.container.GridLayout
        CitationTextArea                matlab.ui.control.TextArea
        CitationTextAreaLabel           matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        GithubRepositoryButton          matlab.ui.control.Button
        ViewPreprintButton              matlab.ui.control.Button
        LaboratoryPageButton            matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        parent_app;
        image_data;
        image_view;
        repo_neurons;
        image_neurons;
        path;
        dir;
        vidArray;
        vreader;
        neuron_table;
        red; % Color indices.
        green;
        blue;
        white;
        gfp;
        dic;
        image_gamma;
        data_store;
        v_master;
        v_data;
        zen_chunk;
        master_path;
    end
    
    methods (Access = private)
        

        function data_packer(app)
            d = uiprogressdlg(app.NeuroPAL_IDTraceWindowUIFigure,'Title','Packaging metadata...','Indeterminate','on');
            if ~exist([app.dir, 'trace'],"dir")
                [status, msg, msgid] = mkdir([app.dir, 'trace']);
                if status ~= 1
                    fprintf('Failed to create directory %s: %s\n', [app.dir, 'trace'], msg);
                end
            end
            close(d)
        end

        function zeph_wrapper(app, routine)
            app.data_packer()
            
            switch routine
                case 'recommend_frames'
                    title = 'Analyzing frames...';
                case 'annotator'
                    title = 'Loading annotator...';
                case 'tracker'
                    title = 'Tracking neurons...';
                case 'tracer'
                    title = 'Calculating traces...';
            end

            d = uiprogressdlg(app.NeuroPAL_IDTraceWindowUIFigure,'Title',title,'Indeterminate','on');
            python_command = ['External_Dependencies\ZephIR\example\npal-wrapper.py ',app.master_path, ' ', routine]
            pyrunfile(python_command);
            close(d)
        end

        function openMinimalBrowser(app, url)

            %app.NeuroPAL_IDTraceWindowUIFigure.WindowState = 'maximized';
            
            screenSize = get(0, 'ScreenSize');
            axesPosition = app.UIAxes.Position;
            gridPosition = app.GridLayout6.Position;
            figurePosition = app.NeuroPAL_IDTraceWindowUIFigure.Position;
            x = figurePosition(1) + axesPosition(1);
            y = screenSize(4) - (figurePosition(2) + axesPosition(2) + axesPosition(4));
            width = axesPosition(3);
            height = gridPosition(4);
            position = [x, y, width, height];
            
            % Absolute path to the packaged Electron application
            [pathToThisFile, ~, ~] = fileparts(mfilename('fullpath'));
            exePath = fullfile(pathToThisFile, 'External_Dependencies', 'ZephIR', 'web-wrap', 'wrap-browser-win32-x64', 'wrap-browser.exe');
            
            args = sprintf('--x %d --y %d --width %d --height %d', position(1), position(2), position(3), position(4))
            batchFile = 'startElectronApp.bat';
            fid = fopen(batchFile, 'wt');
            fprintf(fid, 'start "" "%s" %s', exePath, args);
            fclose(fid);

            set(app.FinishIDButton,'Visible','on');
            set(app.FinishIDButton,'Enable','on');
            set(app.IDCurrentFrameButton,'Visible','off');
            
            system(batchFile);
            delete(batchFile);

        end

        function load_from_nwb(app, file)

            try
                d = uiprogressdlg(app.NeuroPAL_IDTraceWindowUIFigure,'Title','Loading movie...','Indeterminate','on');
                whole_file = nwbRead(file);
                gc_data = whole_file.acquisition.get('GCaMP_series');
                gc_data = permute(gc_data,[2,3,1,4]);
                close(d)
            catch
                'Unable to load any movie.'
            end

            app.v_master.data = gc_data.data.load();
            app.v_master.time = size(gc_data,4);

            t_stamps = gc_data.timestamps;
            app.v_master.num_frames = size(t_stamps,1);

            if strcmp(gc_data.timestamps_unit,'seconds')
                app.v_master.v_length = app.v_master.num_frames;
            else
                app.v_master.v_length = app.v_master.num_frames/gc_data.starting_time_rate;
            end

            app.v_master.nat_fps = gc_data.starting_time_rate;
            app.framerate.Value = app.v_master.nat_fps;
            app.v_master.cache_idx = ceil((1:app.v_master.num_frames)/(app.ChunkSize.Value));
            app.v_master.ChunkSize = [size(gc_data,1), size(gc_data,2), size(gc_data,3)];
        end

        function load_from_h5(app, file)
            app.v_master.data = h5info(file,'/data');
            app.v_master.time = h5info(file,'/times');

            t_stamps = h5read(file, '/times'); % # of timestamps in file
            app.v_master.num_frames = size(t_stamps,1);
            app.v_master.v_length = t_stamps(end) - t_stamps(1); % True length of video (seconds)
            app.v_master.nat_fps = size(t_stamps,1)/app.v_master.v_length; % True framerate
            app.framerate.Value = app.v_master.nat_fps;
            app.v_master.cache_idx = ceil((1:app.v_master.num_frames)/(app.ChunkSize.Value));
        end

        %% Load specific file
        function load_f(app,file)
            
            d = uiprogressdlg(app.NeuroPAL_IDTraceWindowUIFigure,'Title','Reading file...','Indeterminate','on');
            app.v_master = [];
            app.v_master.file = file;
            app.v_master.zen_chunk = 'None';

            app.v_master.file(end-3:end)
            if strcmp(app.v_master.file(end-3:end),'.nwb')
                app.load_from_nwb(file);
            else
                app.load_from_h5(file);
            end
            
            close(d)

            d = uiprogressdlg(app.NeuroPAL_IDTraceWindowUIFigure,'Title','Loading chunk...','Indeterminate','on');
            app.load_t('frames',1,round(app.v_master.data.ChunkSize(3)/2));
            close(d)

            d = uiprogressdlg(app.NeuroPAL_IDTraceWindowUIFigure,'Title','Constructing arrays...','Indeterminate','on');
            app.build_vc() % Build video controller
            app.build_zc() % Build z controller
            app.build_c() % Build chunks
            close(d)
        end

        %% Build video controller
        function build_vc(app)
            ticks = [];
            min_ticks = [];

            app.v_master.num_frames

            for n = 1:app.v_master.num_frames
                if n == 1 || n == app.v_master.num_frames || any(ismember(ticks,n-round(app.v_master.num_frames/5)))
                    ticks = [ticks, n];
                else
                    min_ticks = [min_ticks, n];
                end
            end

            % Convert major tick locations from seconds to minutes and seconds
            majorTickLabels = cell(1, length(ticks));
            for n = 1:size(ticks,2)
                ticks(n)
                min = floor((ticks(n)/app.framerate.Value)/60)
                sec = round((ticks(n)/app.framerate.Value) - min*60)
                majorTickLabels{n} = sprintf('%02d:%02d', min, sec)
            end
            
            app.VideoPlayback.MajorTicks = ticks;
            app.VideoPlayback.MajorTickLabels = majorTickLabels;
            app.VideoPlayback.Limits = [0,max(app.v_master.num_frames)];
        end

        %% Build z controller
        function build_zc(app)
            ticks = [];
            min_ticks = [];

            for n = 1:size(app.v_master.raw_video, 3)

                if n == 1 || n == size(app.v_master.raw_video,3) || ismember(n-2, ticks)
                    ticks = [ticks, n];
                else
                    min_ticks = [min_ticks, n];
                end

            end

            app.Slider.MajorTicks = ticks;
            app.Slider.MinorTicks = min_ticks;
            app.Slider.Value = round(size(app.v_master.raw_video, 3)/2);
            app.Slider.Limits = [1, size(app.v_master.raw_video, 3)];

            app.UIAxes.XLim = [1, app.v_master.data.ChunkSize(2)];
            app.UIAxes.YLim = [1, app.v_master.data.ChunkSize(1)];
        end

        %% Build chunks from time
        function build_c(app)

            % Build chunk visualizer (hardcoded numbers stem from AppDesigner java)
            plain_cache = ceil((app.v_master.num_frames)/(app.ChunkSize.Value)); % # of chunks.
            chunk_marks = ceil((app.Slider.Position(3))/plain_cache); % Calculate marker width.
            app.UIAxes2.Position(1) = app.UIAxes2.Position(1)+12; % Position x.
            app.UIAxes2.Position(2) = app.UIAxes2.Position(2)+30; % Position y.
            app.UIAxes2.Position(4) = app.UIAxes2.Position(4)/2; % Adjust height.

            % Delete any previous chunk markers
            rect = findall(app.UIAxes2,'Type','rectangle');
            delete(rect);

            % Draw chunk marks.
            for n = 1:plain_cache
                rectangle(app.UIAxes2, 'Position', [chunk_marks*(n-1),app.Slider.Position(2),chunk_marks,app.Slider.Position(4)],'EdgeColor','w','FaceColor','r');
            end

        end

        %% Load specific chunk within current file
        function chunk = load_c(app,t_chunk)
            d = uiprogressdlg(app.NeuroPAL_IDTraceWindowUIFigure,'Title','Loading chunk...','Indeterminate','on');
            chunk = h5read(app.v_master.file,'/data', [1 1 1 1 max(app.ChunkSize.Value*(t_chunk-1),1)], [app.v_master.data.ChunkSize(1) app.v_master.data.ChunkSize(2) app.v_master.data.ChunkSize(3) app.v_master.data.ChunkSize(4) app.ChunkSize.Value]);
            close(d)
        end

        %% Load specific time within current chunk
        function load_t(app,t_format,t_time,slice)

             if ~exist('slice','var')
                 % If no slice listed, use slice slider.
                  slice = round(app.Slider.Value);
             end

            switch string(t_format)
                case 'seconds'
                    % If target time is in seconds, convert to frames.
                    t_time = t_time * app.framerate.Value;
                case 'none'
                    t_format = 'frames';
                    t_time = round(app.vreader.CurrentTime*app.framerate.Value);
            end

            t_chunk = app.v_master.cache_idx(t_time); % ID target chunk.

            % Check whether new chunk needs to be loaded.
            if t_chunk == app.v_master.zen_chunk
                % If no, calculate target frame within current chunk.
                faux_frame = max(t_time - ((app.ChunkSize.Value)*(app.v_master.zen_chunk-1)),1);
                if slice ~= app.v_master.zen_slice
                    app.prod_vid(app.v_master.raw_video,slice);
                end
            else 
                % If yes, update current chunk.
                app.v_master.last_chunk = app.v_master.zen_chunk;
                app.v_master.zen_chunk = t_chunk;
                app.v_master.raw_video = app.load_c(t_chunk);

                % Then calculate target frame within new chunk.
                lower_t_bound = ((app.ChunkSize.Value)*t_chunk)-(app.ChunkSize.Value);
                faux_frame = round(max(t_time - lower_t_bound,1));

                % Generate video from new chunk.
                app.prod_vid(app.v_master.raw_video,slice);
                
            end

            % Initialize array to hold video frames
            frame = read(app.vreader,faux_frame);
            imshow(frame, 'Parent', app.UIAxes);

        end

        %% Generate video for slice z at time t
        function prod_vid(app,input,slice)
            %input = app.arr2img(input, slice);

            % Determine the color channel indices.
            red = app.red;
            green = app.green;
            blue = app.blue;
            white = app.white;
            dic = app.dic;
            gfp = app.gfp;

            % Determine the channel=color assignments for displaying.
            color_indices = [red, green, blue];
            input = input(:,:,round(slice),color_indices,:);

            % Adjust the gamma.
            % Note: the image only shows RGB. We added the other channels
            % (W, DIC, GFP) to the RGB in order to show these as well.

            input = uint16(double(intmax('uint16')) * ...
                double(input)/double(max(input(:))));

            for c = 1:size(input, 4)
                if app.image_gamma(c) ~= 1
                    input(:,:,:,c,:) = ...
                        imadjustn(squeeze(input(:,:,:,c,:)),[],[], ...
                        app.image_gamma(c));
                end
            end

            input = double(input)/double(max(input(:)));

            % Note: the image only shows RGB. We added the other channels
            % (W, DIC, GFP) to the RGB in order to show these as well.
            input = squeeze(max(input,[],3));

            vw_cache = VideoWriter([app.dir,'cache']);
            app.v_master.framerate = app.framerate.Value;
            app.v_master.zen_slice = slice;
            vw_cache.FrameRate = app.framerate.Value;
            open(vw_cache);
            writeVideo(vw_cache,input);
            close(vw_cache);
            app.vreader = VideoReader([app.dir,'cache.avi']);
        end

        %% Draw the annotated image (image volume & neuron markers).
        function DrawImageData(app, slice)

            % Is there an image?
            if isempty(app.image_data)
                return;
            end

            % Determine the color channel indices.
            red = app.red;
            green = app.green;
            blue = app.blue;
            white = app.white;
            dic = app.dic;
            gfp = app.gfp;

            % Determine the channel=color assignments for displaying.
            color_indices = [red, green, blue];

            % Draw the 3 color channels.
            app.image_view = app.image_data(:,:,slice,color_indices);

            % Remove unchecked color channels.
            if ~app.RCheckbox.Value % Red
                app.image_view(:,:,slice,red) = 0;
            end
            if ~app.GCheckbox.Value % Green
                app.image_view(:,:,slice,green) = 0;
            end
            if ~app.BCheckbox.Value % Blue
                app.image_view(:,:,slice,blue) = 0;
            end

            % Add in the white channel.
            if app.WCheckbox.Value % White

                % Compute the white channel.
                wchannel = app.image_data(:,:,slice,white);

                % Adjust the gamma.
                W_i = app.gamma_RGBW_DIC_GFP_index(4); % white channel gamma index
                if length(app.image_gamma) >= W_i && app.image_gamma(W_i) ~= 1
                    wchannel = imadjustn(wchannel,[],[], app.image_gamma(W_i));
                end

                % Add the white channel.
                app.image_view = app.image_view + repmat(wchannel, [1,1,1,3]);
            end

            % Add in the GFP channel.
            if app.GFPCheckbox.Value % GFP

                % Compute the GFP channel.
                gfp_color = Program.GUIPreferences.instance().GFP_color;
                gfp_channel = app.image_data(:,:,slice,gfp);

                % Adjust the gamma.
                GFP_i = app.gamma_RGBW_DIC_GFP_index(6); % GFP channel gamma index
                if length(app.image_gamma) >= GFP_i && app.image_gamma(GFP_i) ~= 1
                    gfp_channel = imadjustn(gfp_channel,[],[], app.image_gamma(GFP_i));
                end

                % Add the GFP channel.
                gfp_channel = repmat(gfp_channel, [1,1,1,3]);
                gfp_channel(:,:,slice,~gfp_color) = 0;
                app.image_view = app.image_view + gfp_channel;
            end

            % Adjust the gamma.
            % Note: the image only shows RGB. We added the other channels
            % (W, DIC, GFP) to the RGB in order to show these as well.

            app.image_view = uint16(double(intmax('uint16')) * ...
                double(app.image_view)/double(max(app.image_view(:))));
            for c = 1:size(app.image_view, 4)
                if app.image_gamma(c) ~= 1
                    app.image_view(:,:,:,c) = ...
                        imadjustn(squeeze(app.image_view(:,:,:,c)),[],[], ...
                        app.image_gamma(c));
                end
            end
            app.image_view = double(app.image_view)/double(max(app.image_view(:)));

            % Redraw the max projection.
            % Note: the image only shows RGB. We added the other channels
            % (W, DIC, GFP) to the RGB in order to show these as well.
            image(app.UIAxes, squeeze(max(app.image_view,[],3)));
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parent_app, image_data, image_view, image_neurons, path, dir, red, green, blue, white, dic, gfp, image_gamma)
            app.parent_app = parent_app;
            app.image_data = image_data;
            app.image_view = image_view;
            app.repo_neurons = image_neurons;
            app.image_neurons = image_neurons;
            app.repo_neurons = app.repo_neurons;
            app.image_gamma = image_gamma;
            app.path = path;
            app.dir = dir;

            app.red = str2double(red);
            app.green = str2double(green);
            app.blue = str2double(blue);
            app.white = str2double(white);
            app.dic = str2double(dic);
            app.gfp = str2double(gfp);

            % Resize the figure to fit most of the screen size.
            screen_size = get(groot, 'ScreenSize');
            screen_size = screen_size(3:4);
            screen_margin = floor(screen_size .* [0.07,0.05]);
            figure_size(1:2) = screen_margin / 2;
            figure_size(3) = screen_size(1) - screen_margin(1);
            figure_size(4) = screen_size(2) - 2*screen_margin(2);
            drawnow; % need to draw before resizing
            app.NeuroPAL_IDTraceWindowUIFigure.Position = figure_size;

            image(app.Npal_Axes, squeeze(max(app.image_view,[],3)));

            app.Npal_Axes.XLim = [1, size(app.image_data, 2)];
            app.Npal_Axes.YLim = [1, size(app.image_data, 1)];
        end

        % Button pushed function: ViewPreprintButton
        function ViewPreprintButtonPushed(app, event)
            web('https://www.biorxiv.org/content/10.1101/2022.07.18.500485v1')
        end

        % Button pushed function: GithubRepositoryButton
        function GithubRepositoryButtonPushed(app, event)
            web('https://github.com/venkatachalamlab/ZephIR')
        end

        % Button pushed function: LaboratoryPageButton
        function LaboratoryPageButtonPushed(app, event)
            web('https://venkatachalamlab.org/')
        end

        % Button pushed function: ClicktoselectvideofileButton
        function ClicktoselectvideofileButtonPushed(app, event)
            % Prompt user to select video file
            [file,path] = uigetfile({'*.h5;*.nwb','Supported Files (*.h5, *.nwb)'},'Select video file');
    
            d = uiprogressdlg(app.NeuroPAL_IDTraceWindowUIFigure,'Title','Processing data...',...
'Indeterminate','on');
            app.load_f([path,file]);

            if ~exist([path, 'trace'], 'dir')
                [status, msg, msgid] = mkdir([path, 'trace']);
                if status ~= 1
                    fprintf('Failed to create directory %s: %s\n', directory, msg);
                end
            end

            app.master_path = path;

            set(app.Slider,'Enable','On');
            set(app.SetbookmarkButton,'Enable','On');
            set(app.AdjustAlignmentButton_3,'Enable','On');
            set(app.ForwardButton,'Enable','On');
            set(app.IDCurrentFrameButton,'Enable','On');
            set(app.PlayButton,'Enable','On');
            set(app.RUNTRACKERButton,'Enable','On');
            set(app.RunTracerButton,'Enable','On');
            set(app.SaveResultsButton_3,'Enable','On');
            set(app.ReverseButton,'Enable','On');
            set(app.DropDown,'Enable','On');
            set(app.EditField,'Enable','On');
            set(app.RecommendFramesButton_2,'Enable','On');
            set(app.Slider_4,'Enable','On');
            set(app.ListBox,'Enable','On');
            set(app.VideoPlayback,'Enable','On');
            set(app.framerate,'Enable','On');
            set(app.EnableManualSettingsCheckBox,'Enable','On');
            set(app.ClicktoselectvideofileButton,'Visible','Off');

        end

        % Button pushed function: RUNTRACKERButton
        function RUNTRACKERButtonPushed2(app, event)
            check = uiconfirm(app.NeuroPAL_IDTraceWindowUIFigure,'Depending on your computer, this may take a while. Are you sure you want to proceed?','Warning!','Options',{'Yes','No'},'DefaultOption','Yes');

            switch check
                case 'Yes'
    
                    d = uiprogressdlg(app.NeuroPAL_IDTraceWindowUIFigure,'Title','Processing data...','Indeterminate','on');
                    
                    app.data_packer();
                    
                    close(d)
                    
                    d = uiprogressdlg(app.NeuroPAL_IDTraceWindowUIFigure,'Title','Running trace...','Indeterminate','on');
                    
                    app.zeph_wrapper('tracker');
                    
                    close(d)
                    
                    d = uiprogressdlg(app.NeuroPAL_IDTraceWindowUIFigure,'Title','Rendering results...','Indeterminate','on');
                    
                    close(d)

                case 'No'
                    return
            end
        end

        % Button pushed function: RunTracerButton
        function RunTracerButtonPushed2(app, event)
            app.zeph_wrapper('tracer');
            fileName = [app.dir,name];

            % Read the CSV file
            dataTable = readtable(fileName);
        
            % Initialize the map
            app.neuron_activity_by_name = containers.Map('KeyType', 'char', 'ValueType', 'any');
        
            % Iterate through the rows
            for i = 1:size(dataTable, 1)
                % Get the neuron name
                try
                    neuronName = dataTable.Var2{i};
                catch
                    %fprintf('Error accessing Var2 at row %d\n', i);
                    continue
                end
        
                % Debugging print statement
                %fprintf('Processing neuron: %s\n', neuronName);
        
                % Initialize an array to store the neuron activity
                neuronActivity = [];
        
                % Iterate through the columns starting from column 6
                for j = 6:size(dataTable, 2)
                    % Append the activity to the array if it's not a NaN
                    if ~isnan(dataTable{i,j})
                        neuronActivity = [neuronActivity, dataTable{i, j}];
                    end
                end
        
                % If the neuron has activity, save it to the map
                if ~isempty(neuronActivity)
                    app.neuron_activity_by_name(neuronName) = neuronActivity;
                    %fprintf('Saved activity for neuron: %s\n', neuronName);
                end
            end
        end

        % Value changed function: PlayButton
        function PlayButtonValueChanged2(app, event)
            value = app.PlayButton.Value;
            
            if value == 1
                app.PlayButton.Text = '⏸';
                read(app.vreader,min(max(round(app.VideoPlayback.Value),1),app.VideoPlayback.Limits(2)));

                while (hasFrame(app.vreader) && (app.PlayButton.Value == 1))
                    frame = readFrame(app.vreader);
                    if frame < app.ChunkSize.Value
                        imshow(frame, 'Parent', app.UIAxes);   % <--- use the Parent property
                        %app.DrawNeurons();
                        pause(2/app.framerate.Value);
                    else
                        app.load_v(frame+(app.v_master.zen_chunk*app.ChunkSize.Value))
                        frame = readFrame(app.vreader);
                        imshow(frame, 'Parent', app.UIAxes);   % <--- use the Parent property
                        %app.DrawNeurons();
                        pause(2/app.framerate.Value);
                    end
                end
            
            else
                app.PlayButton.Text = '▶';
                frame = readFrame(app.vreader);
                imshow(frame, 'Parent', app.UIAxes);   % <--- use the Parent property
            end
        end

        % Value changed function: EnableManualSettingsCheckBox
        function EnableManualSettingsCheckBoxValueChanged(app, event)
            if app.EnableManualSettingsCheckBox.Value == 1
                
                set(app.use_gpu,'Enable','On')
                set(app.epoch,'Enable','On')
                set(app.epoch_d,'Enable','On')
                set(app.n_chunks,'Enable','On')

                set(app.clip_grad,'Enable','On')
                set(app.l_t,'Enable','On')
                set(app.l_d,'Enable','On')
                set(app.l_n,'Enable','On')
                set(app.z_comp,'Enable','On')
                set(app.lr_coeff,'Enable','On')
                set(app.lr_floor,'Enable','On')
                set(app.l_n_mode,'Enable','On')
                set(app.nn_max,'Enable','On')
            else
                set(app.epoch,'Enable','Off')
                set(app.epoch_d,'Enable','Off')
                set(app.n_chunks,'Enable','Off')
                set(app.use_gpu,'Enable','Off')
                set(app.clip_grad,'Enable','Off')
                set(app.l_t,'Enable','Off')
                set(app.l_d,'Enable','Off')
                set(app.l_n,'Enable','Off')
                set(app.z_comp,'Enable','Off')
                set(app.lr_coeff,'Enable','Off')
                set(app.lr_floor,'Enable','Off')
                set(app.l_n_mode,'Enable','Off')
                set(app.nn_max,'Enable','Off')
            end
        end

        % Value changed function: VideoPlayback
        function VideoPlaybackValueChanged(app, event)
            round(app.VideoPlayback.Value)
            app.load_t('frames',round(app.VideoPlayback.Value))
        end

        % Value changed function: framerate
        function framerateValueChanged(app, event)
            app.prod_vid(app.v_master.raw_video,app.Slider.Value);
        end

        % Button pushed function: ForwardButton
        function ForwardButtonPushed(app, event)
            app.vreader.CurrentTime = app.vreader.CurrentTime+10;
            frame = readFrame(app.vreader);
            app.VideoPlayback.Value = app.vreader.CurrentTime;
            imshow(frame, 'Parent', app.UIAxes);
        end

        % Button pushed function: ReverseButton
        function ReverseButtonPushed(app, event)
            app.vreader.CurrentTime = app.vreader.CurrentTime-10;
            frame = readFrame(app.vreader);
            app.VideoPlayback.Value = app.vreader.CurrentTime;
            imshow(frame, 'Parent', app.UIAxes);
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            switch app.DropDown.Value
                case 'Frame'
                    frame = read(app.vreader,value);
                    app.VideoPlayback.Value = app.vreader.CurrentTime;
                    imshow(frame, 'Parent', app.UIAxes);
                case 'Second'
                    app.vreader.CurrentTime = value;
                    frame = readFrame(app.vreader);
                    app.VideoPlayback.Value = app.vreader.CurrentTime;
                    imshow(frame, 'Parent', app.UIAxes);
                case 'Minute'
                    app.vreader.CurrentTime = value*60;
                    frame = readFrame(app.vreader);
                    app.VideoPlayback.Value = app.vreader.CurrentTime;
                    imshow(frame, 'Parent', app.UIAxes);
                case 'Bookmark'
                    target = app.ListBox.ItemsData(value);
                    frame = read(app.vreader,target);
                    app.VideoPlayback.Value = app.vreader.CurrentTime;
                    imshow(frame, 'Parent', app.UIAxes);
            end
        end

        % Button pushed function: SetbookmarkButton
        function SetbookmarkButtonPushed(app, event)
            bmLabel = ['Bookmark #',int2str(size(app.ListBox.Items,2)+1)];
            bmFrame = app.vreader.CurrentTime;
            app.ListBox.Items = [app.ListBox.Items, bmLabel];
            if size(app.ListBox.ItemsData)>0
                app.ListBox.ItemsData = [app.ListBox.ItemsData, bmFrame];
            else
                app.ListBox.ItemsData = [bmFrame];
            end
            
        end

        % Clicked callback: ListBox
        function ListBoxClicked(app, event)
            item = round(event.InteractionInformation.Item);
            app.load_t('frames',item);
        end

        % Button pushed function: Up
        function UpPushed(app, event)
            switch app.SelectionButtonGroup.SelectedObject.Text
                case 'Move Neurons'
                    for n = 1:size(app.repo_neurons.neurons,2)
                        app.repo_neurons.neurons(n).position(1) = app.repo_neurons.neurons(n).position(1)-app.MoveEditField_2.Value;
                    end
        
                    app.DrawNeurons();
                case 'Move Video'
                    
            end
        end

        % Button pushed function: AdjustAlignmentButton_3
        function AdjustAlignmentButton_3Pushed(app, event)
            set(app.AdjustAlignmentPanel,'Visible','On');
        end

        % Button pushed function: SaveResultButton
        function SaveResultButtonPushed(app, event)
            set(app.AdjustAlignmentPanel,'Visible','Off');
        end

        % Button pushed function: Right
        function RightButtonPushed(app, event)
            for n = 1:size(app.repo_neurons.neurons,2)
                app.repo_neurons.neurons(n).position(2) = app.repo_neurons.neurons(n).position(2)+app.MoveEditField_2.Value;
            end

            app.DrawNeurons();
        end

        % Button pushed function: Left
        function LeftButtonPushed(app, event)
            for n = 1:size(app.repo_neurons.neurons,2)
                app.repo_neurons.neurons(n).position(2) = app.repo_neurons.neurons(n).position(2)-app.MoveEditField_2.Value;
            end

            app.DrawNeurons();
        end

        % Button pushed function: Down
        function DownButtonPushed(app, event)
            for n = 1:size(app.repo_neurons.neurons,2)
                app.repo_neurons.neurons(n).position(1) = app.repo_neurons.neurons(n).position(1)+app.MoveEditField_2.Value;
            end

            app.DrawNeurons();
        end

        % Button pushed function: FlipHorizontallyButton
        function FlipHorizontallyButtonPushed(app, event)
            % Find the center of the bounding box
            bbox = [inf, -inf, inf, -inf, inf, -inf];
            for n = 1:size(app.image_neurons.neurons, 2)
                pos = app.image_neurons.neurons(n).position;
                bbox(1) = min(bbox(1), pos(1));
                bbox(2) = max(bbox(2), pos(1));
                bbox(3) = min(bbox(3), pos(2));
                bbox(4) = max(bbox(4), pos(2));
                bbox(5) = min(bbox(5), pos(3));
                bbox(6) = max(bbox(6), pos(3));
            end
            center = [(bbox(1)+bbox(2))/2, (bbox(3)+bbox(4))/2, (bbox(5)+bbox(6))/2];
        
            % Flip all neurons horizontally around the center point
            for n = 1:size(app.image_neurons.neurons, 2)
                pos = app.image_neurons.neurons(n).position;
                new_pos = [pos(1), 2*center(2)-pos(2), pos(3)];
                app.image_neurons.neurons(n).position = new_pos;
            end
        
            app.DrawNeurons();
        end

        % Button pushed function: FlipVerticallyButton
        function FlipVerticallyButtonPushed(app, event)
            % Find the center of the bounding box
            bbox = [inf, -inf, inf, -inf, inf, -inf];
            for n = 1:size(app.image_neurons.neurons, 2)
                pos = app.image_neurons.neurons(n).position;
                bbox(1) = min(bbox(1), pos(1));
                bbox(2) = max(bbox(2), pos(1));
                bbox(3) = min(bbox(3), pos(2));
                bbox(4) = max(bbox(4), pos(2));
                bbox(5) = min(bbox(5), pos(3));
                bbox(6) = max(bbox(6), pos(3));
            end
            center = [(bbox(1)+bbox(2))/2, (bbox(3)+bbox(4))/2, (bbox(5)+bbox(6))/2];
        
            % Flip all neurons vertically around the center point
            for n = 1:size(app.image_neurons.neurons, 2)
                pos = app.image_neurons.neurons(n).position;
                new_pos = [2*center(1)-pos(1), pos(2), pos(3)];
                app.image_neurons.neurons(n).position = new_pos;
            end
        
            app.DrawNeurons();
        end

        % Button pushed function: CCW
        function CCWButtonPushed(app, event)
            % Find bounding box
            min_x = Inf; max_x = -Inf;
            min_y = Inf; max_y = -Inf;
            for n = 1:size(app.repo_neurons.neurons,2)
                pos = app.repo_neurons.neurons(n).position;
                if pos(1) < min_x, min_x = pos(1); end
                if pos(1) > max_x, max_x = pos(1); end
                if pos(2) < min_y, min_y = pos(2); end
                if pos(2) > max_y, max_y = pos(2); end
            end
            
            % Compute center of bounding box
            center_x = (min_x + max_x) / 2;
            center_y = (min_y + max_y) / 2;
            
            % Translate all neurons to origin
            for n = 1:size(app.repo_neurons.neurons,2)
                app.repo_neurons.neurons(n).position(1) = app.repo_neurons.neurons(n).position(1) - center_x;
                app.repo_neurons.neurons(n).position(2) = app.repo_neurons.neurons(n).position(2) - center_y;
            end
            
            % Compute rotation matrix
            theta = pi/2; % rotation angle (90 degrees)
            R = [cos(theta) -sin(theta); sin(theta) cos(theta)]; % 2D rotation matrix
            
            % Apply rotation matrix to each position vector
            for n = 1:size(app.repo_neurons.neurons,2)
                position = app.repo_neurons.neurons(n).position(1:2); % extract x and y coordinates
                position = R * position'; % apply rotation matrix
                app.repo_neurons.neurons(n).position(1:2) = position'; % update x and y coordinates
            end
            
            % Translate all neurons back to their original position
            for n = 1:size(app.repo_neurons.neurons,2)
                app.repo_neurons.neurons(n).position(1) = app.repo_neurons.neurons(n).position(1) + center_x;
                app.repo_neurons.neurons(n).position(2) = app.repo_neurons.neurons(n).position(2) + center_y;
            end
            
            app.DrawNeurons();
        end

        % Button pushed function: CenterHorizontallyButton
        function CenterHorizontallyButtonPushed(app, event)
            % Find the vertical extent of the neurons
            min_y = Inf; max_y = -Inf;
            for n = 1:size(app.repo_neurons.neurons,2)
                pos = app.repo_neurons.neurons(n).position;
                if pos(2) < min_y, min_y = pos(2); end
                if pos(2) > max_y, max_y = pos(2); end
            end
            
            % Compute the center of the UIAxes
            center_y = mean(app.UIAxes.YLim);
            
            % Compute the displacement needed to center the neurons vertically
            dy = center_y - (min_y + max_y) / 2;
            
            % Translate all neurons by the computed displacement
            for n = 1:size(app.repo_neurons.neurons,2)
                app.repo_neurons.neurons(n).position(2) = app.repo_neurons.neurons(n).position(2) + dy;
            end
            
            app.DrawNeurons();
        end

        % Button pushed function: CenterVerticallyButton
        function CenterVerticallyButtonPushed(app, event)
            % Find the horizontal extent of the neurons
            min_x = Inf; max_x = -Inf;
            for n = 1:size(app.repo_neurons.neurons,2)
                pos = app.repo_neurons.neurons(n).position;
                if pos(1) < min_x, min_x = pos(1); end
                if pos(1) > max_x, max_x = pos(1); end
            end
            
            % Compute the center of the UIAxes
            center_x = mean(app.UIAxes.XLim);
            
            % Compute the displacement needed to center the neurons horizontally
            dx = center_x - (min_x + max_x) / 2;
            
            % Translate all neurons by the computed displacement
            for n = 1:size(app.repo_neurons.neurons,2)
                app.repo_neurons.neurons(n).position(1) = app.repo_neurons.neurons(n).position(1) + dx;
            end
            
            app.DrawNeurons();
        end

        % Close request function: NeuroPAL_IDTraceWindowUIFigure
        function NeuroPAL_IDTraceWindowUIFigureCloseRequest(app, event)
            app.repo_neurons = app.image_neurons;
            delete(app)
        end

        % Value changed function: ScaleEditField
        function ScaleEditFieldValueChanged(app, event)
            % Get the scaling factor from the TransformScale slider
            scale_factor = app.ScaleEditField.Value;
        
            % Find the center of the bounding box
            bbox = [inf, -inf, inf, -inf, inf, -inf];
            for n = 1:size(app.image_neurons.neurons, 2)
                pos = app.image_neurons.neurons(n).position;
                bbox(1) = min(bbox(1), pos(1));
                bbox(2) = max(bbox(2), pos(1));
                bbox(3) = min(bbox(3), pos(2));
                bbox(4) = max(bbox(4), pos(2));
                bbox(5) = min(bbox(5), pos(3));
                bbox(6) = max(bbox(6), pos(3));
            end
            center = [(bbox(1)+bbox(2))/2, (bbox(3)+bbox(4))/2, (bbox(5)+bbox(6))/2];
        
            % Scale all neurons around the center point
            for n = 1:size(app.image_neurons.neurons, 2)
                pos = app.image_neurons.neurons(n).position;
                displacement = pos - center;
                new_displacement = displacement * scale_factor;
                app.image_neurons.neurons(n).position = center + new_displacement;
            end
        
            app.DrawNeurons();
        end

        % Value changed function: Slider_4
        function Slider_4ValueChanged(app, event)
            app.Slider_4.Value = round(app.Slider_4.Value);
            
        end

        % Button pushed function: RecommendFramesButton_2
        function RecommendFramesButton_2Pushed(app, event)
            app.zeph_wrapper('recommend_frames');

            file_path = fullfile(app.dir, 'metadata.json');
            data = jsondecode(fileread(file_path));
            keys = fieldnames(data);
            t_ref_fn_keys = startsWith(keys, 't_ref_fn');
            max_num = 0;
            for i = 1:numel(keys)
                if t_ref_fn_keys(i)
                    num = str2double(extractAfter(keys{i}, 't_ref_fn'));
                    if num > max_num
                        max_num = num;
                        values = data.(keys{i});
                    end
                end
            end

            t_frames = unique(values);

            d = uiprogressdlg(app.NeuroPAL_IDTraceWindowUIFigure,'Title','Creating bookmarks...','Indeterminate','on');
            for n=1:size(t_frames)
                bmLabel = ['Recommended Frame #',int2str(n)];
                bmFrame = t_frames(n);
                app.ListBox.Items = [app.ListBox.Items, bmLabel];
                if size(app.ListBox.ItemsData)>0
                    app.ListBox.ItemsData = [app.ListBox.ItemsData, bmFrame];
                else
                    app.ListBox.ItemsData = [bmFrame];
                end
            end
            close(d)
        end

        % Button pushed function: ResetMoveButton
        function ResetMoveButtonPushed(app, event)
            app.repo_neurons.neurons.position = app.image_neurons.neurons.position;
        end

        % Button pushed function: ResetTransformButton
        function ResetTransformButtonPushed(app, event)
            
        end

        % Button pushed function: IDCurrentFrameButton
        function IDCurrentFrameButtonPushed(app, event)
            app.zeph_wrapper('annotator');
            try
                app.openMinimalBrowser('http://localhost:5001/');
            catch
                web('http://localhost:5001/','-new','-notoolbar');
            end
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            app.load_t('none',0,round(app.Slider.Value));
        end

        % Value changed function: RCheckbox
        function RCheckboxValueChanged(app, event)
            app.DrawImageData(round(app.Slider.Value));
        end

        % Value changed function: GCheckbox
        function GCheckboxValueChanged(app, event)
            app.DrawImageData(round(app.Slider.Value));
        end

        % Value changed function: BCheckbox
        function BCheckboxValueChanged(app, event)
            app.DrawImageData(round(app.Slider.Value));
        end

        % Value changed function: WCheckbox
        function WCheckboxValueChanged(app, event)
            app.DrawImageData(round(app.Slider.Value));
        end

        % Value changed function: GFPCheckbox
        function GFPCheckboxValueChanged(app, event)
            app.DrawImageData(round(app.Slider.Value));
        end

        % Value changed function: LysosomeCheckbox
        function LysosomeCheckboxValueChanged(app, event)
            app.DrawImageData(round(app.Slider.Value));
        end

        % Value changed function: GutCheckBox
        function GutCheckBoxValueChanged(app, event)
            app.DrawImageData(round(app.Slider.Value));
        end

        % Key press function: NeuroPAL_IDTraceWindowUIFigure
        function NeuroPAL_IDTraceWindowUIFigureKeyPress(app, event)
            % Is there an image?
            if isempty(app.image_data)
                return;
            end

            % What did the user press?
            key = event.Key;
            switch key

                case '0' % Toggle the ID atlas.
                    app.ColorAtlasCheckBox.Value = ~app.ColorAtlasCheckBox.Value;
                    app.DrawImageData(round(app.Slider.Value));

                case '1' % Toggle the red channel.
                    app.RCheckBox.Value = ~app.RCheckBox.Value;
                    app.DrawImageData(round(app.Slider.Value));

                case '2' % Toggle the green channel.
                    app.GCheckBox.Value = ~app.GCheckBox.Value;
                    app.DrawImageData(round(app.Slider.Value));

                case '3' % Toggle the blue channel.
                    app.BCheckBox.Value = ~app.BCheckBox.Value;
                    app.DrawImageData(round(app.Slider.Value));

                case '4' % Toggle the white channel.
                    app.WCheckBox.Value = ~app.WCheckBox.Value;
                    app.DrawImageData(round(app.Slider.Value));

                case '5' % Toggle the DIC channel.
                    app.DICCheckBox.Value = ~app.DICCheckBox.Value;
                    app.DrawImageData(round(app.Slider.Value));

                case '6' % Toggle the GFP channel.
                    app.GFPCheckBox.Value = ~app.GFPCheckBox.Value;
                    app.DrawImageData(round(app.Slider.Value));

                case 'leftarrow' % Go up in the z stack.
                    if app.Slider.Value > 1
                        zEvent.Value = app.Slider.Value - 1;
                        app.SliderValueChanged(zEvent);
                    end
                    %disp(key);

                case 'rightarrow' % Go down in the z stack.
                    if app.Slider.Value < app.Slider.Limits(2)
                        zEvent.Value = app.Slider.Value + 1;
                        app.SliderValueChanged(zEvent);
                    end
                    %disp(key);

                case 'uparrow' % Zoom in.
                    zoom(app.UIAxes, 1 + 0.2);

                case 'downarrow' % Zoom out.
                    zoom(app.UIAxes, 1 - 0.2);

                case {'h','H'} % Toggle half zoom.
                    % Reset x-axis limits.
                    app.UIAxes.XLim = [1, size(app.image_data, 2)];

                    % Upper -> lower half.
                    ylims = app.UIAxes.YLim;
                    if ylims(1) < size(app.image_data, 1)/2
                        app.UIAxes.YLim = [ceil(size(app.image_data, 1)/2), ...
                            size(app.image_data, 1)];

                        % Lower -> upper half.
                    else
                        app.UIAxes.YLim = [1, floor(size(app.image_data, 1)/2)];
                    end

                case {'j','J'} % Toggle third zoom.
                    % Reset x-axis limits.
                    app.UIAxes.XLim = [1, size(app.image_data, 2)];

                    % Upper -> middle third.
                    ylims = app.UIAxes.YLim;
                    if ylims(1) < size(app.image_data, 1)/3
                        app.UIAxes.YLim = [ceil(size(app.image_data, 1)/3), ...
                            floor(size(app.image_data, 1)*2/3)];

                        % Middle -> lower third.
                    elseif ylims(1) < size(app.image_data, 1)*2/3
                        app.UIAxes.YLim = [ceil(size(app.image_data, 1)*2/3), ...
                            size(app.image_data, 1)];

                        % Lower -> upper third.
                    else
                        app.UIAxes.YLim = [1, floor(size(app.image_data, 1)/3)];
                    end

                case {'k','K'} % Toggle quarter zoom.
                    % Reset x-axis limits.
                    app.UIAxes.XLim = [1, size(app.image_data, 2)];

                    % Upper -> upper-middle quarter.
                    ylims = app.UIAxes.YLim;
                    if ylims(1) < size(app.image_data, 1)/4
                        app.UIAxes.YLim = [ceil(size(app.image_data, 1)/4), ...
                            floor(size(app.image_data, 1)*2/4)];

                        % Upper-middle -> lower-middle quarter.
                    elseif ylims(1) < size(app.image_data, 1)*2/4
                        app.UIAxes.YLim = [ceil(size(app.image_data, 1)*2/4), ...
                            floor(size(app.image_data, 1)*3/4)];

                        % Lower-middle -> lower quarter.
                    elseif ylims(1) < size(app.image_data, 1)*3/4
                        app.UIAxes.YLim = [ceil(size(app.image_data, 1)*3/4), ...
                            size(app.image_data, 1)];

                        % Lower -> upper quarter.
                    else
                        app.UIAxes.YLim = [1, floor(size(app.image_data, 1)/4)];
                    end

                case {'p','P'} % Toggle image panning.

                    % Toggle image panning.
                    if app.is_panning
                        pan(app.UIAxes,'off');
                        pause(0.5);
                        RestorePointer(app);
                        app.is_panning = false;
                    else
                        pan(app.UIAxes,'on');
                        pause(0.5);
                        app.is_panning = true;
                    end

                case {'r','R'} % Reset the figure to center.
                    app.UIAxes.XLim = [1, size(app.image_data, 2)];
                    app.UIAxes.YLim = [1, size(app.image_data, 1)];
                    %app.XY.XLim = [1, size(app.image_data, 2)];
                    %app.XY.YLim = [1, size(app.image_data, 1)];
                    %app.XY.XAxisLocation = 'bottom';
                    %app.XY.YAxisLocation = 'left';

                    %case {'x','X'} % Switch mouse to crosshairs.
                    %zoom(app.XY,'on');
                    %pause(0.2);
                    %zoom(app.XY,'off');

                otherwise
                    % Do nothing.
            end

        end

        % Value changed function: ChunkSize
        function ChunkSizeValueChanged(app, event)
            value = app.ChunkSize.Value;
            app.build_c()
            app.load_t('none')
        end

        % Button pushed function: SaveNeuronIDsButton
        function SaveNeuronIDsButtonPushed(app, event)
            set(app.IDCurrentFramePanel,'Enable','Off');
        end

        % Button pushed function: RunAutoSegmentationButton
        function RunAutoSegmentationButtonPushed(app, event)
            
        end

        % Button pushed function: FinishIDButton
        function FinishIDButtonPushed(app, event)
            set(app.FinishIDButton,'Visible','off');
            set(app.FinishIDButton,'Enable','off');
            set(app.IDCurrentFrameButton,'Visible','on');
            app.NeuroPAL_IDTraceWindowUIFigure.WindowState = 'normal';
            try
                webread('http://localhost:5002/close');
            catch
                warning('Failed to close Electron window');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.NeuroPAL_IDTraceWindowUIFigure = uifigure('Visible', 'off');
            app.NeuroPAL_IDTraceWindowUIFigure.Name = 'NeuroPAL_ID Trace Window';
            app.NeuroPAL_IDTraceWindowUIFigure.CloseRequestFcn = createCallbackFcn(app, @NeuroPAL_IDTraceWindowUIFigureCloseRequest, true);
            app.NeuroPAL_IDTraceWindowUIFigure.KeyPressFcn = createCallbackFcn(app, @NeuroPAL_IDTraceWindowUIFigureKeyPress, true);
            app.GridLayout6 = uigridlayout(app.NeuroPAL_IDTraceWindowUIFigure);
            app.UIAxes = uiaxes(app.GridLayout6);
            title(app.UIAxes, 'Trace')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.TabGroup = uitabgroup(app.GridLayout6);
            app.VideoControlsTab = uitab(app.TabGroup);
            app.VideoControlsTab.Title = 'Video Controls';
            app.Panel_4 = uipanel(app.VideoControlsTab);
            app.GridLayout12 = uigridlayout(app.Panel_4);
            app.UIAxes2 = uiaxes(app.GridLayout12);
            app.UIAxes2.XTickLabel = '';
            app.UIAxes2.YTickLabel = '';
            app.Panel_5 = uipanel(app.GridLayout12);
            app.GridLayout28 = uigridlayout(app.Panel_5);
            app.RUNTRACKERButton = uibutton(app.GridLayout28, 'push');
            app.RUNTRACKERButton.ButtonPushedFcn = createCallbackFcn(app, @RUNTRACKERButtonPushed2, true);
            app.RUNTRACKERButton.Enable = 'off';
            app.RUNTRACKERButton.Text = 'RUN TRACKER';
            app.RunTracerButton = uibutton(app.GridLayout28, 'push');
            app.RunTracerButton.ButtonPushedFcn = createCallbackFcn(app, @RunTracerButtonPushed2, true);
            app.RunTracerButton.Enable = 'off';
            app.RunTracerButton.Text = 'Run Tracer';
            app.Panel_8 = uipanel(app.GridLayout12);
            app.GridLayout8 = uigridlayout(app.Panel_8);
            app.SkipToolPanel = uipanel(app.GridLayout8);
            app.SkipToolPanel.Title = 'Skip Tool';
            app.GridLayout11 = uigridlayout(app.SkipToolPanel);
            app.DropDown = uidropdown(app.GridLayout11);
            app.DropDown.Items = {'Frame', 'Second', 'Minute', 'Bookmark'};
            app.DropDown.Enable = 'off';
            app.DropDown.Value = 'Second';
            app.EditField = uieditfield(app.GridLayout11, 'numeric');
            app.EditField.ValueDisplayFormat = '%.0f';
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Enable = 'off';
            app.JumptoLabel = uilabel(app.GridLayout11);
            app.JumptoLabel.Text = 'Jump to...';
            app.SettingsPanel = uipanel(app.GridLayout8);
            app.SettingsPanel.Title = 'Settings';
            app.GridLayout10 = uigridlayout(app.SettingsPanel);
            app.framerate = uieditfield(app.GridLayout10, 'numeric');
            app.framerate.ValueDisplayFormat = '%.0f';
            app.framerate.ValueChangedFcn = createCallbackFcn(app, @framerateValueChanged, true);
            app.framerate.Enable = 'off';
            app.framerate.Value = 30;
            app.fpsLabel = uilabel(app.GridLayout10);
            app.fpsLabel.Text = 'fps.';
            app.FramerateLabel = uilabel(app.GridLayout10);
            app.FramerateLabel.Text = 'Framerate:';
            app.ChunkSizeEditFieldLabel = uilabel(app.GridLayout10);
            app.ChunkSizeEditFieldLabel.Text = 'Chunk Size:';
            app.ChunkSize = uieditfield(app.GridLayout10, 'numeric');
            app.ChunkSize.ValueDisplayFormat = '%.0f';
            app.ChunkSize.ValueChangedFcn = createCallbackFcn(app, @ChunkSizeValueChanged, true);
            app.ChunkSize.Value = 500;
            app.framesLabel = uilabel(app.GridLayout10);
            app.framesLabel.Text = 'frames.';
            app.Panel_11 = uipanel(app.GridLayout8);
            app.GridLayout9 = uigridlayout(app.Panel_11);
            app.PlayButton = uibutton(app.GridLayout9, 'state');
            app.PlayButton.ValueChangedFcn = createCallbackFcn(app, @PlayButtonValueChanged2, true);
            app.PlayButton.Enable = 'off';
            app.PlayButton.Text = '▶';
            app.ReverseButton = uibutton(app.GridLayout9, 'push');
            app.ReverseButton.ButtonPushedFcn = createCallbackFcn(app, @ReverseButtonPushed, true);
            app.ReverseButton.Enable = 'off';
            app.ReverseButton.Text = '⏮';
            app.ForwardButton = uibutton(app.GridLayout9, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.ForwardButton.Enable = 'off';
            app.ForwardButton.Text = '⏭';
            app.SetbookmarkButton = uibutton(app.GridLayout8, 'push');
            app.SetbookmarkButton.ButtonPushedFcn = createCallbackFcn(app, @SetbookmarkButtonPushed, true);
            app.SetbookmarkButton.Enable = 'off';
            app.SetbookmarkButton.Text = 'Set bookmark';
            app.BookmarksPanel = uipanel(app.GridLayout8);
            app.BookmarksPanel.Title = 'Bookmarks';
            app.GridLayout13 = uigridlayout(app.BookmarksPanel);
            app.ListBox = uilistbox(app.GridLayout13);
            app.ListBox.Items = {};
            app.ListBox.Enable = 'off';
            app.ListBox.ClickedFcn = createCallbackFcn(app, @ListBoxClicked, true);
            app.ListBox.Value = {};
            app.VideoPlayback = uislider(app.GridLayout12);
            app.VideoPlayback.ValueChangedFcn = createCallbackFcn(app, @VideoPlaybackValueChanged, true);
            app.VideoPlayback.Enable = 'off';
            app.Panel_6 = uipanel(app.GridLayout12);
            app.GridLayout29 = uigridlayout(app.Panel_6);
            app.IDCurrentFrameButton = uibutton(app.GridLayout29, 'push');
            app.IDCurrentFrameButton.ButtonPushedFcn = createCallbackFcn(app, @IDCurrentFrameButtonPushed, true);
            app.IDCurrentFrameButton.Enable = 'off';
            app.IDCurrentFrameButton.Text = 'ID Current Frame';
            app.RecommendFramesButton_2 = uibutton(app.GridLayout29, 'push');
            app.RecommendFramesButton_2.ButtonPushedFcn = createCallbackFcn(app, @RecommendFramesButton_2Pushed, true);
            app.RecommendFramesButton_2.Enable = 'off';
            app.RecommendFramesButton_2.Text = 'Recommend Frames';
            app.AdjustAlignmentButton_3 = uibutton(app.GridLayout29, 'push');
            app.AdjustAlignmentButton_3.ButtonPushedFcn = createCallbackFcn(app, @AdjustAlignmentButton_3Pushed, true);
            app.AdjustAlignmentButton_3.Enable = 'off';
            app.AdjustAlignmentButton_3.Text = 'Adjust Alignment';
            app.SaveResultsButton_3 = uibutton(app.GridLayout29, 'push');
            app.SaveResultsButton_3.Enable = 'off';
            app.SaveResultsButton_3.Text = 'Save Results';
            app.FinishIDButton = uibutton(app.GridLayout29, 'push');
            app.FinishIDButton.ButtonPushedFcn = createCallbackFcn(app, @FinishIDButtonPushed, true);
            app.FinishIDButton.Enable = 'off';
            app.FinishIDButton.Visible = 'off';
            app.FinishIDButton.Text = 'Finish ID';
            app.BasicSettingsTab = uitab(app.TabGroup);
            app.BasicSettingsTab.Title = 'Basic Settings';
            app.GridLayout4 = uigridlayout(app.BasicSettingsTab);
            app.EnableManualSettingsCheckBox = uicheckbox(app.GridLayout4);
            app.EnableManualSettingsCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnableManualSettingsCheckBoxValueChanged, true);
            app.EnableManualSettingsCheckBox.Enable = 'off';
            app.EnableManualSettingsCheckBox.Text = ' Enable Manual Settings';
            app.MotionPanel = uipanel(app.GridLayout4);
            app.MotionPanel.Title = 'Motion';
            app.GridLayout5 = uigridlayout(app.MotionPanel);
            app.Slider_4 = uislider(app.GridLayout5);
            app.Slider_4.Limits = [0 3];
            app.Slider_4.MajorTicks = [0 1 2 3];
            app.Slider_4.MajorTickLabels = {'None', 'Low', 'Mid', 'High'};
            app.Slider_4.ValueChangedFcn = createCallbackFcn(app, @Slider_4ValueChanged, true);
            app.Slider_4.MinorTicks = [];
            app.Slider_4.Enable = 'off';
            app.Panel_12 = uipanel(app.GridLayout4);
            app.GridLayout2 = uigridlayout(app.Panel_12);
            app.SetsdevicetoGPUandenablesCUDAuseDropDownLabel = uilabel(app.GridLayout2);
            app.SetsdevicetoGPUandenablesCUDAuseDropDownLabel.Text = 'Sets device to GPU and enables CUDA use.';
            app.use_gpu = uidropdown(app.GridLayout2);
            app.use_gpu.Items = {'True', 'False'};
            app.use_gpu.Editable = 'on';
            app.use_gpu.Enable = 'off';
            app.use_gpu.Value = 'True';
            app.UseGPULabel = uilabel(app.GridLayout2);
            app.UseGPULabel.Text = 'Use GPU';
            app.nn_maxLabel = uilabel(app.GridLayout2);
            app.nn_maxLabel.Tooltip = {'default: 5'};
            app.nn_maxLabel.Text = 'nn_max';
            app.EditFieldLabel_2 = uilabel(app.GridLayout2);
            app.EditFieldLabel_2.Tooltip = {'default: 5'};
            app.EditFieldLabel_2.Text = 'Maximum number of neighboring keypoints to be connected by springs for calculating λN.';
            app.nn_max = uieditfield(app.GridLayout2, 'numeric');
            app.nn_max.ValueDisplayFormat = '%.2f';
            app.nn_max.Enable = 'off';
            app.nn_max.Tooltip = {'default: 5'};
            app.EditField6Label = uilabel(app.GridLayout2);
            app.EditField6Label.Text = 'Coefficient for initial learning rate, multiplied by the distance between current frame and its parent.';
            app.lr_coeff = uieditfield(app.GridLayout2, 'numeric');
            app.lr_coeff.ValueDisplayFormat = '%.2f';
            app.lr_coeff.Enable = 'off';
            app.lr_coeff.Tooltip = {'TIP: If a dynamic learning rate is not necessary for your dataset, set to 0 or lower to disable. This will also skip calculation of distances between frames which can be very computationally costly for long datasets.'};
            app.lr_coeff.Value = 2;
            app.LRCoeffLabel = uilabel(app.GridLayout2);
            app.LRCoeffLabel.Tooltip = {'TIP: If a dynamic learning rate is not necessary for your dataset, set to 0 or lower to disable. This will also skip calculation of distances between frames which can be very computationally costly for long datasets.'};
            app.LRCoeffLabel.Text = 'LR Coeff';
            app.NLabel = uilabel(app.GridLayout2);
            app.NLabel.Tooltip = {'Spring constants are calculated by multiplying the covariance of connected pairs by this number and passing the result through a ReLU layer. The resulting loss is also rescaled to this value, meaning it cannot exceed this value. If a covariance value is unavailable, the spring constant is set equal to this number. default: 1.0'; ''; 'TIP: Increase up to 10.0 for non-deforming datasets. Decrease down to 0.01 or turn off for large motion/deformation. Optimal value tends to be between 1.0-4.0. Set to 0 or -1 if regularization is unnecessary (this can speed up performance).'};
            app.NLabel.Text = 'λN';
            app.EditField4Label = uilabel(app.GridLayout2);
            app.EditField4Label.Tooltip = {'Spring constants are calculated by multiplying the covariance of connected pairs by this number and passing the result through a ReLU layer. The resulting loss is also rescaled to this value, meaning it cannot exceed this value. If a covariance value is unavailable, the spring constant is set equal to this number. default: 1.0'; ''; 'TIP: Increase up to 10.0 for non-deforming datasets. Decrease down to 0.01 or turn off for large motion/deformation. Optimal value tends to be between 1.0-4.0. Set to 0 or -1 if regularization is unnecessary (this can speed up performance).'};
            app.EditField4Label.Text = 'Coefficient for spring constant for intra-keypoint spatial regularization, λN.';
            app.l_n = uieditfield(app.GridLayout2, 'numeric');
            app.l_n.ValueDisplayFormat = '%.2f';
            app.l_n.Enable = 'off';
            app.l_n.Tooltip = {'Spring constants are calculated by multiplying the covariance of connected pairs by this number and passing the result through a ReLU layer. The resulting loss is also rescaled to this value, meaning it cannot exceed this value. If a covariance value is unavailable, the spring constant is set equal to this number. default: 1.0'; ''; 'TIP: Increase up to 10.0 for non-deforming datasets. Decrease down to 0.01 or turn off for large motion/deformation. Optimal value tends to be between 1.0-4.0. Set to 0 or -1 if regularization is unnecessary (this can speed up performance).'};
            app.l_n.Value = 5;
            app.NmodeLabel = uilabel(app.GridLayout2);
            app.NmodeLabel.Tooltip = {'''disp'': use inter-keypoint displacements'; '''norm'': use inter-keypoint distances (rotation is not penalized)'; '''ljp'': use a Lenard-Jones potential on inter-keypoint distances (collapsing onto the same position is highly penalized)'; 'default: ''disp'''};
            app.NmodeLabel.Text = 'λN mode';
            app.MethodtouseforcalculatingNLabel = uilabel(app.GridLayout2);
            app.MethodtouseforcalculatingNLabel.Text = 'Method to use for calculating LN.';
            app.l_n_mode = uidropdown(app.GridLayout2);
            app.l_n_mode.Items = {'disp', 'norm', 'ljp'};
            app.l_n_mode.Editable = 'on';
            app.l_n_mode.Enable = 'off';
            app.l_n_mode.Value = 'disp';
            app.ChannelViewerPanel = uipanel(app.GridLayout4);
            app.ChannelViewerPanel.Title = 'Channel Viewer';
            app.GridLayout19 = uigridlayout(app.ChannelViewerPanel);
            app.RCheckbox = uicheckbox(app.GridLayout19);
            app.RCheckbox.ValueChangedFcn = createCallbackFcn(app, @RCheckboxValueChanged, true);
            app.RCheckbox.Text = 'Red';
            app.RCheckbox.Value = true;
            app.GCheckbox = uicheckbox(app.GridLayout19);
            app.GCheckbox.ValueChangedFcn = createCallbackFcn(app, @GCheckboxValueChanged, true);
            app.GCheckbox.Text = 'Green';
            app.GCheckbox.Value = true;
            app.BCheckbox = uicheckbox(app.GridLayout19);
            app.BCheckbox.ValueChangedFcn = createCallbackFcn(app, @BCheckboxValueChanged, true);
            app.BCheckbox.Text = 'Blue';
            app.BCheckbox.Value = true;
            app.WCheckbox = uicheckbox(app.GridLayout19);
            app.WCheckbox.ValueChangedFcn = createCallbackFcn(app, @WCheckboxValueChanged, true);
            app.WCheckbox.Text = 'White';
            app.GFPCheckbox = uicheckbox(app.GridLayout19);
            app.GFPCheckbox.ValueChangedFcn = createCallbackFcn(app, @GFPCheckboxValueChanged, true);
            app.GFPCheckbox.Text = 'GFP';
            app.LysosomeCheckbox = uicheckbox(app.GridLayout19);
            app.LysosomeCheckbox.ValueChangedFcn = createCallbackFcn(app, @LysosomeCheckboxValueChanged, true);
            app.LysosomeCheckbox.Enable = 'off';
            app.LysosomeCheckbox.Text = 'Lysosome';
            app.GutCheckBox = uicheckbox(app.GridLayout19);
            app.GutCheckBox.ValueChangedFcn = createCallbackFcn(app, @GutCheckBoxValueChanged, true);
            app.GutCheckBox.Enable = 'off';
            app.GutCheckBox.Text = 'Gut';
            app.AdvancedSettingsTab = uitab(app.TabGroup);
            app.AdvancedSettingsTab.Title = 'Advanced Settings';
            app.GridLayout = uigridlayout(app.AdvancedSettingsTab);
            app.EditFieldLabel = uilabel(app.GridLayout);
            app.EditFieldLabel.Tooltip = {'TIP: If motion is small, set lower to ~0.1. This is a more aggressive tactic than lr_ceiling.'};
            app.EditFieldLabel.Text = 'Maximum value for gradients for gradient descent. Use -1 to uncap. default: 1.0';
            app.clip_grad = uieditfield(app.GridLayout, 'numeric');
            app.clip_grad.ValueDisplayFormat = '%.2f';
            app.clip_grad.Enable = 'off';
            app.clip_grad.Value = -1;
            app.EditField2Label = uilabel(app.GridLayout);
            app.EditField2Label.Tooltip = {'TIP: 0.1 generally matches order of magnitude of registration loss. Increase up to 1.0 for non-deforming datasets. Set to 0 or -1 if regularization is unnecessary (this will skip the regularization step entirely and can dramatically speed up performance). Alternatively, setting n_frame to 1 will also disable this.'};
            app.EditField2Label.Text = 'Coefficient for temporal smoothing loss, λT, enforcing a 0th-order linear fit for intensity over n_frame frames.';
            app.l_t = uieditfield(app.GridLayout, 'numeric');
            app.l_t.ValueDisplayFormat = '%.2f';
            app.l_t.Enable = 'off';
            app.l_t.Value = -1;
            app.EditField3Label = uilabel(app.GridLayout);
            app.EditField3Label.Tooltip = {'This regularization is turned on at the last n_epoch_d of each optimization loop with everything else turned off. Set to -1 to disable. default: -1.0'};
            app.EditField3Label.Text = 'Coefficient for feature detection loss, λD. ';
            app.l_d = uieditfield(app.GridLayout, 'numeric');
            app.l_d.ValueDisplayFormat = '%.2f';
            app.l_d.Enable = 'off';
            app.l_d.Value = -1;
            app.Minimumvalueforinitiallearningratedefault002EditFieldLabel = uilabel(app.GridLayout);
            app.Minimumvalueforinitiallearningratedefault002EditFieldLabel.Text = 'Minimum value for initial learning rate. default: 0.02';
            app.lr_floor = uieditfield(app.GridLayout, 'numeric');
            app.lr_floor.ValueDisplayFormat = '%.2f';
            app.lr_floor.Enable = 'off';
            app.lr_floor.Value = 0.02;
            app.Multiplygradientsinthezaxisby1z_compensatorEditFieldLabel = uilabel(app.GridLayout);
            app.Multiplygradientsinthezaxisby1z_compensatorEditFieldLabel.Tooltip = {'Since the internal coordinate system is rescaled from -1 to 1 in all directions, gradients in the z-axis may be too small when there is a large disparity between the xy- and z-shapes of the dataset, and thus fail to track motion in the z-axis. Increasing this will compensate for the disparity. Note that gradients will still be clipped to (clip_grad * z_compensator) if clip_grad is enabled. Set to 0 or -1 to disable. default: -1'};
            app.Multiplygradientsinthezaxisby1z_compensatorEditFieldLabel.Text = 'Multiply gradients in the z-axis by (1 + z_compensator).';
            app.z_comp = uieditfield(app.GridLayout, 'numeric');
            app.z_comp.ValueDisplayFormat = '%.2f';
            app.z_comp.Enable = 'off';
            app.z_comp.Value = -1;
            app.clip_gradLabel = uilabel(app.GridLayout);
            app.clip_gradLabel.Tooltip = {'TIP: If motion is small, set lower to ~0.1. This is a more aggressive tactic than lr_ceiling.'};
            app.clip_gradLabel.Text = 'clip_grad';
            app.tLabel = uilabel(app.GridLayout);
            app.tLabel.Tooltip = {'TIP: 0.1 generally matches order of magnitude of registration loss. Increase up to 1.0 for non-deforming datasets. Set to 0 or -1 if regularization is unnecessary (this will skip the regularization step entirely and can dramatically speed up performance). Alternatively, setting n_frame to 1 will also disable this.'};
            app.tLabel.Text = 'λt';
            app.dLabel = uilabel(app.GridLayout);
            app.dLabel.Tooltip = {'This regularization is turned on at the last n_epoch_d of each optimization loop with everything else turned off. Set to -1 to disable. default: -1.0'};
            app.dLabel.Text = 'λd';
            app.LRFloorLabel = uilabel(app.GridLayout);
            app.LRFloorLabel.Text = 'LR Floor';
            app.ZCompensatorLabel = uilabel(app.GridLayout);
            app.ZCompensatorLabel.Tooltip = {'Since the internal coordinate system is rescaled from -1 to 1 in all directions, gradients in the z-axis may be too small when there is a large disparity between the xy- and z-shapes of the dataset, and thus fail to track motion in the z-axis. Increasing this will compensate for the disparity. Note that gradients will still be clipped to (clip_grad * z_compensator) if clip_grad is enabled. Set to 0 or -1 to disable. default: -1'};
            app.ZCompensatorLabel.Text = 'Z Compensator';
            app.HoveroverlabelsforfurtherdetailsorguidanceLabel = uilabel(app.GridLayout);
            app.HoveroverlabelsforfurtherdetailsorguidanceLabel.Text = 'Hover over labels for further details or guidance.';
            app.NumberofiterationsforimageregistrationLREditFieldLabel = uilabel(app.GridLayout);
            app.NumberofiterationsforimageregistrationLREditFieldLabel.Text = 'Number of iterations for image registration, LR.';
            app.n_epoch = uieditfield(app.GridLayout, 'numeric');
            app.n_epoch.ValueDisplayFormat = '%.0f';
            app.n_epoch.Enable = 'off';
            app.n_epoch.Value = 40;
            app.EditField2_2Label = uilabel(app.GridLayout);
            app.EditField2_2Label.Text = 'Number of iterations for feature detection regularization, LD.';
            app.n_epoch_d = uieditfield(app.GridLayout, 'numeric');
            app.n_epoch_d.ValueDisplayFormat = '%.0f';
            app.n_epoch_d.Enable = 'off';
            app.n_epoch_d.Value = 10;
            app.n_epochLabel = uilabel(app.GridLayout);
            app.n_epochLabel.Text = 'n_epoch';
            app.n_epoch_dLabel = uilabel(app.GridLayout);
            app.n_epoch_dLabel.Text = 'n_epoch_d';
            app.NumberofstepstodividetheforwardpassintoEditFieldLabel = uilabel(app.GridLayout);
            app.NumberofstepstodividetheforwardpassintoEditFieldLabel.Text = 'Number of steps to divide the forward pass into.';
            app.n_chunks = uieditfield(app.GridLayout, 'numeric');
            app.n_chunks.ValueDisplayFormat = '%.0f';
            app.n_chunks.Enable = 'off';
            app.n_chunks.Value = 10;
            app.n_chunksLabel = uilabel(app.GridLayout);
            app.n_chunksLabel.Text = 'n_chunks';
            app.CreditTab = uitab(app.TabGroup);
            app.CreditTab.Title = 'Credit';
            app.GridLayout14 = uigridlayout(app.CreditTab);
            app.Label = uilabel(app.GridLayout14);
            app.Label.Text = 'Tracing relies on a modified version of Zephir, a multiple object tracking algorithm based on image registration and developed by James Yu et al. ZephIR tracks keypoints in a 2D or 3D movie by registering image descriptors sampled around each keypoint. ';
            app.LaboratoryPageButton = uibutton(app.GridLayout14, 'push');
            app.LaboratoryPageButton.ButtonPushedFcn = createCallbackFcn(app, @LaboratoryPageButtonPushed, true);
            app.LaboratoryPageButton.Text = 'Laboratory Page';
            app.ViewPreprintButton = uibutton(app.GridLayout14, 'push');
            app.ViewPreprintButton.ButtonPushedFcn = createCallbackFcn(app, @ViewPreprintButtonPushed, true);
            app.ViewPreprintButton.Text = 'View Preprint';
            app.GithubRepositoryButton = uibutton(app.GridLayout14, 'push');
            app.GithubRepositoryButton.ButtonPushedFcn = createCallbackFcn(app, @GithubRepositoryButtonPushed, true);
            app.GithubRepositoryButton.Text = 'Github Repository';
            app.Image2 = uiimage(app.GridLayout14);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'External_Dependencies', 'zephir', 'logo.png');
            app.CitationTextAreaLabel = uilabel(app.GridLayout14);
            app.CitationTextAreaLabel.Text = 'Citation';
            app.CitationTextArea = uitextarea(app.GridLayout14);
            app.CitationTextArea.Value = {'Versatile Multiple Object Tracking in Sparse 2D/3D Videos Via Diffeomorphic Image Registration'; 'James Yu, Amin Nejatbakhsh, Mahdi Torkashvand, Sahana Gangadharan, Maedeh Seyedolmohadesin, Jinmahn Kim, Liam Paninski, Vivek Venkatachalam bioRxiv 2022.07.18.500485; doi: https://doi.org/10.1101/2022.07.18.500485'; 'This article is a preprint and has not been certified by peer review'};
            app.NeuroPALVolumePanel = uipanel(app.GridLayout6);
            app.NeuroPALVolumePanel.Title = 'NeuroPAL Volume';
            app.GridLayout7 = uigridlayout(app.NeuroPALVolumePanel);
            app.Npal_Axes = uiaxes(app.GridLayout7);
            app.Npal_Axes.XTickLabel = '';
            app.Npal_Axes.YTick = [0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.Npal_Axes.YTickLabel = '';
            app.ClicktoselectvideofileButton = uibutton(app.GridLayout6, 'push');
            app.ClicktoselectvideofileButton.ButtonPushedFcn = createCallbackFcn(app, @ClicktoselectvideofileButtonPushed, true);
            app.ClicktoselectvideofileButton.Text = 'Click to select video file.';
            app.Panel_14 = uipanel(app.GridLayout6);
            app.GridLayout20 = uigridlayout(app.Panel_14);
            app.Slider = uislider(app.GridLayout20);
            app.Slider.MajorTicks = 0;
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.MinorTicks = 0;
            app.Slider.Enable = 'off';
            app.AdjustAlignmentPanel = uipanel(app.NeuroPAL_IDTraceWindowUIFigure);
            app.AdjustAlignmentPanel.Title = 'Adjust Alignment';
            app.AdjustAlignmentPanel.Visible = 'off';
            app.GridLayout15 = uigridlayout(app.AdjustAlignmentPanel);
            app.TransformPanel = uipanel(app.GridLayout15);
            app.TransformPanel.Title = 'Transform';
            app.GridLayout17 = uigridlayout(app.TransformPanel);
            app.HeightSpinnerLabel = uilabel(app.GridLayout17);
            app.HeightSpinnerLabel.Text = 'Height';
            app.HeightSpinner = uispinner(app.GridLayout17);
            app.WidthSpinnerLabel = uilabel(app.GridLayout17);
            app.WidthSpinnerLabel.Text = 'Width';
            app.WidthSpinner = uispinner(app.GridLayout17);
            app.ScaleEditFieldLabel = uilabel(app.GridLayout17);
            app.ScaleEditFieldLabel.Text = 'Scale';
            app.ScaleEditField = uieditfield(app.GridLayout17, 'numeric');
            app.ScaleEditField.ValueChangedFcn = createCallbackFcn(app, @ScaleEditFieldValueChanged, true);
            app.ResetMoveButton = uibutton(app.GridLayout15, 'push');
            app.ResetMoveButton.ButtonPushedFcn = createCallbackFcn(app, @ResetMoveButtonPushed, true);
            app.ResetMoveButton.Text = 'Reset Move';
            app.SaveResultButton = uibutton(app.GridLayout15, 'push');
            app.SaveResultButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultButtonPushed, true);
            app.SaveResultButton.Text = 'Save Result';
            app.ResetTransformButton = uibutton(app.GridLayout15, 'push');
            app.ResetTransformButton.ButtonPushedFcn = createCallbackFcn(app, @ResetTransformButtonPushed, true);
            app.ResetTransformButton.Enable = 'off';
            app.ResetTransformButton.Text = 'Reset Transform';
            app.MovePanel = uipanel(app.GridLayout15);
            app.MovePanel.Title = 'Move';
            app.GridLayout16 = uigridlayout(app.MovePanel);
            app.FlipHorizontallyButton = uibutton(app.GridLayout16, 'push');
            app.FlipHorizontallyButton.ButtonPushedFcn = createCallbackFcn(app, @FlipHorizontallyButtonPushed, true);
            app.FlipHorizontallyButton.Text = 'Flip Horizontally';
            app.FlipVerticallyButton = uibutton(app.GridLayout16, 'push');
            app.FlipVerticallyButton.ButtonPushedFcn = createCallbackFcn(app, @FlipVerticallyButtonPushed, true);
            app.FlipVerticallyButton.Text = 'Flip Vertically';
            app.CenterVerticallyButton = uibutton(app.GridLayout16, 'push');
            app.CenterVerticallyButton.ButtonPushedFcn = createCallbackFcn(app, @CenterVerticallyButtonPushed, true);
            app.CenterVerticallyButton.Text = 'Center Vertically';
            app.CenterHorizontallyButton = uibutton(app.GridLayout16, 'push');
            app.CenterHorizontallyButton.ButtonPushedFcn = createCallbackFcn(app, @CenterHorizontallyButtonPushed, true);
            app.CenterHorizontallyButton.Text = 'Center Horizontally';
            app.Panel_13 = uipanel(app.GridLayout16);
            app.GridLayout18 = uigridlayout(app.Panel_13);
            app.pixelsatatimeLabel = uilabel(app.GridLayout18);
            app.pixelsatatimeLabel.Text = 'pixels at a time.';
            app.MoveEditField_2 = uieditfield(app.GridLayout18, 'numeric');
            app.MoveEditField_2.ValueDisplayFormat = '%.0f';
            app.MoveEditField_2.Value = 5;
            app.MoveLabel = uilabel(app.GridLayout18);
            app.MoveLabel.Text = 'Move ';
            app.Panel_15 = uipanel(app.GridLayout16);
            app.GridLayout27 = uigridlayout(app.Panel_15);
            app.CW = uibutton(app.GridLayout27, 'push');
            app.CW.Text = '↻';
            app.CCW = uibutton(app.GridLayout27, 'push');
            app.CCW.ButtonPushedFcn = createCallbackFcn(app, @CCWButtonPushed, true);
            app.CCW.Text = '↺';
            app.Down = uibutton(app.GridLayout27, 'push');
            app.Down.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.Down.Text = '⬇️';
            app.Right = uibutton(app.GridLayout27, 'push');
            app.Right.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.Right.Text = '➡️';
            app.Up = uibutton(app.GridLayout27, 'push');
            app.Up.ButtonPushedFcn = createCallbackFcn(app, @UpPushed, true);
            app.Up.Text = '⬆️';
            app.Left = uibutton(app.GridLayout27, 'push');
            app.Left.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.Left.Text = '⬅️';
            app.SelectionButtonGroup = uibuttongroup(app.GridLayout15);
            app.SelectionButtonGroup.Title = 'Selection';
            app.MoveNeuronsButton = uiradiobutton(app.SelectionButtonGroup);
            app.MoveNeuronsButton.Text = 'Move Neurons';
            app.MoveNeuronsButton.Value = true;
            app.MoveVideoButton = uiradiobutton(app.SelectionButtonGroup);
            app.MoveVideoButton.Text = 'Move Video';
            app.MoveVolumeNeuronsButton = uiradiobutton(app.SelectionButtonGroup);
            app.MoveVolumeNeuronsButton.Text = 'Move Volume & Neurons';
            app.IDCurrentFramePanel = uipanel(app.NeuroPAL_IDTraceWindowUIFigure);
            app.IDCurrentFramePanel.Title = 'ID Current Frame';
            app.IDCurrentFramePanel.Visible = 'off';
            app.GridLayout21 = uigridlayout(app.IDCurrentFramePanel);
            app.SettingsPanel_3 = uipanel(app.GridLayout21);
            app.SettingsPanel_3.Title = 'Settings';
            app.GridLayout22 = uigridlayout(app.SettingsPanel_3);
            app.OnclickLabel = uilabel(app.GridLayout22);
            app.OnclickLabel.Text = 'On click:';
            app.OnclickDropDown = uidropdown(app.GridLayout22);
            app.OnclickDropDown.Items = {'Add Neuron', 'Detect Neuron'};
            app.OnclickDropDown.Value = 'Add Neuron';
            app.SaveNeuronIDsButton = uibutton(app.GridLayout22, 'push');
            app.SaveNeuronIDsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveNeuronIDsButtonPushed, true);
            app.SaveNeuronIDsButton.Text = 'Save Neuron IDs';
            app.MoveEditField_3Label = uilabel(app.GridLayout22);
            app.MoveEditField_3Label.Text = 'Move';
            app.MoveEditField_3 = uieditfield(app.GridLayout22, 'numeric');
            app.MoveEditField_3.ValueDisplayFormat = '%.0f';
            app.MoveEditField_3.Value = 5;
            app.pixelsatatimeLabel_2 = uilabel(app.GridLayout22);
            app.pixelsatatimeLabel_2.Text = 'pixels at a time.';
            app.NeuronIndexPanel = uipanel(app.GridLayout21);
            app.NeuronIndexPanel.Title = 'Neuron Index';
            app.GridLayout23 = uigridlayout(app.NeuronIndexPanel);
            app.UITable = uitable(app.GridLayout23);
            app.UITable.ColumnName = {'#'; 'ID'; 'x'; 'y'; 'z'};
            app.UITable.RowName = {};
            app.NeuronInformationPanel = uipanel(app.GridLayout21);
            app.NeuronInformationPanel.Title = 'Neuron Information';
            app.GridLayout24 = uigridlayout(app.NeuronInformationPanel);
            app.NeuronNameEditFieldLabel = uilabel(app.GridLayout24);
            app.NeuronNameEditFieldLabel.Text = 'Neuron Name:';
            app.NeuronNameEditField = uieditfield(app.GridLayout24, 'text');
            app.Button = uibutton(app.GridLayout24, 'push');
            app.Button2 = uibutton(app.GridLayout24, 'push');
            app.Button2.Text = 'Button2';
            app.MoveNeuronPanel = uipanel(app.GridLayout21);
            app.MoveNeuronPanel.Title = 'Move Neuron';
            app.GridLayout25 = uigridlayout(app.MoveNeuronPanel);
            app.Button_2 = uibutton(app.GridLayout25, 'push');
            app.Button_2.Text = '⬆️';
            app.Button_3 = uibutton(app.GridLayout25, 'push');
            app.Button_3.Text = '⬇️';
            app.Button_5 = uibutton(app.GridLayout25, 'push');
            app.Button_5.Text = '⬅️';
            app.Button_4 = uibutton(app.GridLayout25, 'push');
            app.Button_4.Text = '➡️';
            app.AutoFillOptionsPanel = uipanel(app.GridLayout21);
            app.AutoFillOptionsPanel.Title = 'Auto-Fill Options';
            app.GridLayout26 = uigridlayout(app.AutoFillOptionsPanel);
            app.LoadfromclosestFrameButton = uibutton(app.GridLayout26, 'push');
            app.LoadfromclosestFrameButton.Text = 'Load from closest Frame';
            app.Importfromh5fileButton = uibutton(app.GridLayout26, 'push');
            app.Importfromh5fileButton.Text = 'Import from .h5 file';
            app.RunAutoSegmentationButton = uibutton(app.GridLayout26, 'push');
            app.RunAutoSegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @RunAutoSegmentationButtonPushed, true);
            app.RunAutoSegmentationButton.Text = 'Run Auto-Segmentation';
            % Show the figure after all components are created
            app.NeuroPAL_IDTraceWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = trace_window(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NeuroPAL_IDTraceWindowUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NeuroPAL_IDTraceWindowUIFigure)
        end
    end
end